classdef cosmic_optilayout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        YAlignButton                    matlab.ui.control.Button
        XAlignButton                    matlab.ui.control.Button
        MeasureModeDropDown             matlab.ui.control.DropDown
        ExportFigureButton              matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        ViewLockLamp                    matlab.ui.control.Lamp
        ViewLockSwitch                  matlab.ui.control.Switch
        LockViewSwitchLabel             matlab.ui.control.Label
        PROPERTYPanel                   matlab.ui.container.Panel
        ElementColorDropDown            matlab.ui.control.DropDown
        ElementColorDropDownLabel       matlab.ui.control.Label
        Diameter                        matlab.ui.control.EditField
        DiameterLabel                   matlab.ui.control.Label
        YField                          matlab.ui.control.EditField
        YmmLabel                        matlab.ui.control.Label
        XField                          matlab.ui.control.EditField
        XmmLabel                        matlab.ui.control.Label
        RotationAngleField              matlab.ui.control.EditField
        RotationAngledegEditFieldLabel  matlab.ui.control.Label
        OPTICSPanel                     matlab.ui.container.Panel
        AddCameraButton                 matlab.ui.control.Button
        AddSurfaceButton                matlab.ui.control.Button
        AddPinholeButton                matlab.ui.control.Button
        AddBSButton                     matlab.ui.control.Button
        AddMirrorsButton                matlab.ui.control.Button
        FocalLength                     matlab.ui.control.EditField
        FocallengthLabel                matlab.ui.control.Label
        AddLensButton                   matlab.ui.control.Button
        AddSampleButton                 matlab.ui.control.Button
        ObjectiveAngleRangeField        matlab.ui.control.EditField
        AngleMaxLabel                   matlab.ui.control.Label
        ObjectiveNAField                matlab.ui.control.EditField
        NAEditFieldLabel                matlab.ui.control.Label
        ObjectiveFocalField             matlab.ui.control.EditField
        FocaldistancemmLabel            matlab.ui.control.Label
        ObjectiveLengthField            matlab.ui.control.EditField
        LengthmmLabel                   matlab.ui.control.Label
        ObjectiveApertureField          matlab.ui.control.EditField
        AperturemmLabel                 matlab.ui.control.Label
        AddObjectiveButton              matlab.ui.control.Button
        LIGHTSOURCEPanel                matlab.ui.container.Panel
        RayCountField                   matlab.ui.control.NumericEditField
        RayCountLabel                   matlab.ui.control.Label
        PointSourceRayCountField        matlab.ui.control.NumericEditField
        RayCountEditFieldLabel          matlab.ui.control.Label
        PointSourceRangeField           matlab.ui.control.NumericEditField
        RangeAngleLabel                 matlab.ui.control.Label
        PointRayTable                   matlab.ui.control.Table
        DeletePointRayButton            matlab.ui.control.Button
        AddPointRayButton               matlab.ui.control.Button
        AddPointSourceButton            matlab.ui.control.Button
        RayColorDropDown                matlab.ui.control.DropDown
        RayColorLabel                   matlab.ui.control.Label
        RayWidthField                   matlab.ui.control.EditField
        RayWidthLabel                   matlab.ui.control.Label
        RayAngleField                   matlab.ui.control.EditField
        RayAngledegEditFieldLabel       matlab.ui.control.Label
        AddRayButton                    matlab.ui.control.Button
        CursorPositionLabel             matlab.ui.control.Label
        SearchField                     matlab.ui.control.EditField
        SearchPressEnterLabel           matlab.ui.control.Label
        YMaxEditFieldLabel              matlab.ui.control.Label
        YMaxField                       matlab.ui.control.NumericEditField
        YMinField                       matlab.ui.control.NumericEditField
        YMinEditFieldLabel              matlab.ui.control.Label
        XMaxField                       matlab.ui.control.NumericEditField
        XMaxEditFieldLabel              matlab.ui.control.Label
        XMinField                       matlab.ui.control.NumericEditField
        XMinEditFieldLabel              matlab.ui.control.Label
        BoxSelectLamp                   matlab.ui.control.Lamp
        BoxSelectSwitch                 matlab.ui.control.Switch
        BoxSelectSwitchLabel            matlab.ui.control.Label
        EnableSelectedButton            matlab.ui.control.Button
        DisableSelectedButton           matlab.ui.control.Button
        MeasureLamp                     matlab.ui.control.Lamp
        MeasureSwitch                   matlab.ui.control.Switch
        MeasureSwitchLabel              matlab.ui.control.Label
        CopySelectedButton              matlab.ui.control.Button
        NameField                       matlab.ui.control.EditField
        NameLabel                       matlab.ui.control.Label
        RedoButton                      matlab.ui.control.Button
        UndoButton                      matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        ZoomLamp                        matlab.ui.control.Lamp
        ZoomSwitch                      matlab.ui.control.Switch
        ZoomSwitchLabel                 matlab.ui.control.Label
        ResetViewButton                 matlab.ui.control.Button
        UnlockSelectedButton            matlab.ui.control.Button
        LockSelectedButton              matlab.ui.control.Button
        LoadLayoutButton                matlab.ui.control.Button
        SaveLayoutButton                matlab.ui.control.Button
        DeleteSelectedButton            matlab.ui.control.Button
        TypeLabel                       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Elements = {};
        SelectedElement = [];
        IsDragging = false;
        RayLineWidth = 0.7; % Description
        CurrentXLim
        CurrentYLim
        UndoStack = {};
        RedoStack = {};
        MaxUndo = 10;
        MeasureFirst = [];
        ShowMeasurements = false;
        Measurements = {};
        SelectedMeasurement = [];
        MeasureMode = false;
        MeasureStart = [];
        SelectedElements = [];
        IsBoxSelecting = false;
        BoxStartPoint = [];
        SelectionBoxHandle = [];
        GroupDragStartPoint = [];
        GroupOriginalPositions = {};
        DragOffset = [0 0];
        ClipboardElements = {};
        ClipboardAnchor = [];
        IsPanning = false;
        PanStartPoint = [];
        PanStartXLim = [];
        PanStartYLim = [];
        ViewLocked = false;

    end

    methods (Access = private)

        %%%%%%%%Drawing
        function drawScene(app)
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            ensureMeasureModeControl(app)
            ensureExportFigureButton(app)
            % invisible-looking large canvas anchor
            plot(app.UIAxes,...
                [-50000 50000 50000 -50000 -50000],...
                [-50000 -50000 50000 50000 -50000],...
                '-',...
                'Color',[1 1 1],...
                'LineWidth',0.01,...
                'HitTest','off',...
                'PickableParts','none');
            for k = 1:length(app.Elements)
                e = app.Elements{k};
                switch e.type

                    case 'Lens'
                        drawLens(app,e,k);

                    case 'Mirror'
                        drawMirror(app,e,k);

                    case 'BS'
                        drawBS(app,e,k);

                    case 'Ray'
                        drawRay(app,e,k);

                    case 'Objective'
                        drawObjective(app,e,k);

                    case 'Camera'
                        drawCamera(app,e,k);

                    case 'Sample'
                        drawSample(app,e,k);

                    case 'Pinhole'
                        drawPinhole(app,e,k);
                        app.drawLockIcon(e);

                    case 'PointSource'
                        drawPointSource(app,e,k);

                    case 'Surface'
                        drawSurface(app,e,k);
                end

            end
            % app.UIAxes.Position = [20 330 1460 500];
            drawMeasurements(app)
            xlim(app.UIAxes,app.CurrentXLim)
            ylim(app.UIAxes,app.CurrentYLim)
            xlabel(app.UIAxes,'X (mm)')
            ylabel(app.UIAxes,'Y (mm)')
            title(app.UIAxes,'Layout')
            app.UIAxes.DataAspectRatioMode = 'auto';
            app.UIAxes.PlotBoxAspectRatioMode = 'auto';
            grid(app.UIAxes,'on')
            traceRays(app)
            if app.ShowMeasurements
                drawAllMeasurements(app)
            end
        end

        function drawLens(app,e,k)

            isDisabled = isfield(e,'disabled') && e.disabled;
            isSelected = isequal(k,app.SelectedElement) || ismember(k,app.SelectedElements);

            if isfield(e,'color')
                edgeColor = e.color;
            else
                edgeColor = [0.35 0.35 0.35];
            end

            if isDisabled
                edgeColor = [0.6 0.6 0.6];
                faceAlpha = 0.15;
                lineStyle = '--';
                lw = 1;
            elseif isSelected
                faceAlpha = 0.65;
                lineStyle = '-';
                lw = 2;
            else
                faceAlpha = 0.65;
                lineStyle = '-';
                lw = 1;
            end

            D = e.D;
            W = 2;

            center = [e.x e.y];

            transDir = [cosd(e.theta), sind(e.theta)];
            axisDir  = [cosd(e.theta+90), sind(e.theta+90)];

            yy = linspace(-D/2,D/2,80);
            halfWidth = (W/2) * (1 - (yy/(D/2)).^2);

            pRight = center + yy(:).*transDir + halfWidth(:).*axisDir;
            pLeft  = center + flipud(yy(:)).*transDir - flipud(halfWidth(:)).*axisDir;

            pts = [pRight; pLeft];

            h = patch(app.UIAxes,...
                pts(:,1), pts(:,2), [0.85 0.85 0.85],...
                'EdgeColor',edgeColor,...
                'LineWidth',lw,...
                'LineStyle',lineStyle,...
                'FaceAlpha',faceAlpha,...
                'HitTest','on',...
                'PickableParts','all');

            h.ButtonDownFcn = @(src,event) startDragElement(app,k);

            app.drawLockIcon(e);

        end

        function drawMirror(app,e,k)

            isDisabled = isfield(e,'disabled') && e.disabled;

            isSelected = isequal(k,app.SelectedElement) || ...
                ismember(k,app.SelectedElements);

            if isfield(e,'color')
                c = e.color;
            else
                c = 'b';
            end

            if isDisabled

                c = [0.5 0.5 0.5];
                lw = 1;

            elseif isSelected

                lw = 2;

            else

                lw = 1;

            end
            if isDisabled
                lineStyle = '--';
            else
                lineStyle = '-';
            end

            L = e.D;
            center = [e.x e.y];
            t = [cosd(e.theta), sind(e.theta)];

            pA = center - L/2*t;
            pB = center + L/2*t;

            h = plot(app.UIAxes,...
                [pA(1) pB(1)],...
                [pA(2) pB(2)],...
                'Color',c,...
                'LineStyle',lineStyle,...
                'LineWidth',lw,...
                'HitTest','on',...
                'PickableParts','all');

            h.ButtonDownFcn = @(src,event) startDragElement(app,k);
            app.drawLockIcon(e);
        end

        function drawBS(app,e,k)

            isDisabled = isfield(e,'disabled') && e.disabled;
            isSelected = isequal(k,app.SelectedElement) || ismember(k,app.SelectedElements);

            if isfield(e,'color')
                edgeColor = e.color;
            else
                edgeColor = [0.1 0.35 0.7];   % default BS blue
            end

            if isDisabled
                edgeColor = [0.5 0.5 0.5];
                faceAlpha = 0.20;
                lineStyle = '--';
                lw = 1;
            elseif isSelected
                faceAlpha = 0.55;
                lineStyle = '-';
                lw = 2;                       % selected = thicker
            else
                faceAlpha = 0.55;
                lineStyle = '-';
                lw = 1;
            end

            D = e.D;

            center = [e.x e.y];

            transDir = [cosd(e.theta), sind(e.theta)];
            axisDir  = [cosd(e.theta+90), sind(e.theta+90)];

            p1 = center - D/2*transDir - D/2*axisDir;
            p2 = center + D/2*transDir - D/2*axisDir;
            p3 = center + D/2*transDir + D/2*axisDir;
            p4 = center - D/2*transDir + D/2*axisDir;

            h = patch(app.UIAxes,...
                [p1(1) p2(1) p3(1) p4(1)],...
                [p1(2) p2(2) p3(2) p4(2)],...
                [0.75 0.88 1.0],...
                'EdgeColor',edgeColor,...
                'LineStyle',lineStyle,...
                'LineWidth',lw,...
                'FaceAlpha',faceAlpha,...
                'HitTest','on',...
                'PickableParts','all');

            h.ButtonDownFcn = @(src,event) startDragElement(app,k);

            q1 = center - D/2*transDir + D/2*axisDir;
            q2 = center + D/2*transDir - D/2*axisDir;

            h2 = plot(app.UIAxes,...
                [q1(1) q2(1)],...
                [q1(2) q2(2)],...
                'Color',edgeColor,...
                'LineStyle',lineStyle,...
                'LineWidth',lw,...
                'HitTest','off',...
                'PickableParts','none');

            h2.HitTest = 'off';
            h2.PickableParts = 'none';

            app.drawLockIcon(e);

        end

        function drawRay(app,e,k)

            isDisabled = isfield(e,'disabled') && e.disabled;

            if isDisabled
                lineStyle = '--';
            else
                lineStyle = '-';
            end

            isSelected = isequal(k,app.SelectedElement) || ismember(k,app.SelectedElements);

            if isSelected
                c = 'r';
            elseif isfield(e,'color')
                c = e.color;
            else
                c = 'g';
            end

            Lpick = 1000;
            p1 = [e.x e.y];
            p2 = p1 + Lpick*[cosd(e.theta), sind(e.theta)];

            hPick = plot(app.UIAxes,...
                [p1(1) p2(1)],...
                [p1(2) p2(2)],...
                '-',...
                'Color','k',...
                'LineWidth',8,...
                'Visible','off',...
                'HitTest','on',...
                'PickableParts','all');

            hPick.ButtonDownFcn = @(src,event) startDragElement(app,k);

            if isSelected
                arrowLength = 120;

                quiver(app.UIAxes,...
                    e.x,...
                    e.y,...
                    arrowLength*cosd(e.theta),...
                    arrowLength*sind(e.theta),...
                    0,...
                    'Color',c,...
                    'LineWidth',2,...
                    'MaxHeadSize',1,...
                    'AutoScale','off',...
                    'HitTest','off',...
                    'PickableParts','none');
            end

            app.drawLockIcon(e);

        end

        function drawObjective(app,e,k)

            isDisabled = isfield(e,'disabled') && e.disabled;
            isSelected = isequal(k,app.SelectedElement) || ismember(k,app.SelectedElements);

            if isfield(e,'color')
                edgeColor = e.color;
            else
                edgeColor = [0.4 0.4 0.4];
            end

            if isDisabled
                edgeColor = [0.5 0.5 0.5];
                faceAlpha = 0.25;
                lineStyle = '--';
                lw = 1;
            elseif isSelected
                faceAlpha = 0.75;
                lineStyle = '-';
                lw = 2;
            else
                faceAlpha = 0.75;
                lineStyle = '-';
                lw = 1;
            end

            frontCenter = [e.x e.y];

            axisDir  = [cosd(e.theta+90), sind(e.theta+90)];
            transDir = [cosd(e.theta), sind(e.theta)];

            L = e.length;
            D = e.aperture;

            backCenter = frontCenter + L*axisDir;

            p1 = frontCenter - (D/2)*transDir;
            p2 = backCenter  - (D/2)*transDir;
            p3 = backCenter  + (D/2)*transDir;
            p4 = frontCenter + (D/2)*transDir;

            h = patch(app.UIAxes,...
                [p1(1) p2(1) p3(1) p4(1)],...
                [p1(2) p2(2) p3(2) p4(2)],...
                [0.82 0.82 0.82],...
                'EdgeColor',edgeColor,...
                'LineStyle',lineStyle,...
                'LineWidth',lw,...
                'FaceAlpha',faceAlpha,...
                'HitTest','on',...
                'PickableParts','all');

            h.ButtonDownFcn = @(src,event) startDragElement(app,k);

            focalCenter = frontCenter - e.f * axisDir;
            backFocalCenter = frontCenter + e.f * axisDir;

            pF1 = focalCenter - (D/2)*transDir;
            pF2 = focalCenter + (D/2)*transDir;

            plot(app.UIAxes,...
                [pF1(1) pF2(1)],...
                [pF1(2) pF2(2)],...
                '--',...
                'Color',edgeColor,...
                'LineWidth',1.5,...
                'HitTest','off',...
                'PickableParts','none');
            pB1 = backFocalCenter - (D/2)*transDir;
            pB2 = backFocalCenter + (D/2)*transDir;

            plot(app.UIAxes,...
                [pB1(1) pB2(1)],...
                [pB1(2) pB2(2)],...
                '--',...
                'Color',edgeColor,...
                'LineWidth',1.5,...
                'HitTest','off',...
                'PickableParts','none');

            offset = 4*transDir;

            text(app.UIAxes,...
                pF2(1)+offset(1),...
                pF2(2)+offset(2),...
                'F',...
                'Color',edgeColor,...
                'FontSize',11,...
                'FontWeight','bold',...
                'HorizontalAlignment','center',...
                'VerticalAlignment','middle',...
                'HitTest','off',...
                'PickableParts','none');
            app.drawLockIcon(e);

            offset = 4*transDir;

            text(app.UIAxes,...
                pB2(1)+offset(1),...
                pB2(2)+offset(2),...
                'BF',...
                'Color',edgeColor,...
                'FontSize',11,...
                'FontWeight','bold',...
                'HorizontalAlignment','left',...
                'VerticalAlignment','middle',...
                'HitTest','off',...
                'PickableParts','none');

        end

        function drawCamera(app,e,k)

            isDisabled = isfield(e,'disabled') && e.disabled;
            isSelected = isequal(k,app.SelectedElement) || ismember(k,app.SelectedElements);

            if isfield(e,'color')
                c = e.color;
            else
                c = 'k';
            end

            if isDisabled
                c = [0.6 0.6 0.6];
                faceAlpha = 0.15;
                lineStyle = '--';
                lw = 1;
            elseif isSelected
                faceAlpha = 0.65;
                lineStyle = '-';
                lw = 2;
            else
                faceAlpha = 0.65;
                lineStyle = '-';
                lw = 1;
            end

            w = e.width;
            hgt = e.height;

            h = rectangle(app.UIAxes,...
                'Position',[e.x-w/2 e.y-hgt/2 w hgt],...
                'EdgeColor',c,...
                'LineStyle',lineStyle,...
                'LineWidth',lw);

            h.ButtonDownFcn = @(src,event) startDragElement(app,k);

            app.drawLockIcon(e);

        end
        function drawSample(app,e,k)

            isDisabled = isfield(e,'disabled') && e.disabled;

            if isDisabled
                alphaValue = 0.25;
                lineStyle = '--';
            else
                alphaValue = 1.0;
                lineStyle = '-';
            end

            if isequal(k,app.SelectedElement) || ismember(k,app.SelectedElements)
                c = 'r';
                lw = 3;
            else
                c = [0.4 0.4 0.4];
                lw = 2;
            end

            s = e.D;

            if isfield(e,'imagePath') && isfile(e.imagePath)

                img = imread(e.imagePath);
                [hImg,wImg,~] = size(img);
                aspect = wImg/hImg;
                w = e.D;
                h = w/aspect;
                h = image(app.UIAxes,...
                    'XData',[e.x-w/2 e.x+w/2],...
                    'YData',[e.y+h/2 e.y-h/2],...
                    'CData',img,...
                    'AlphaData',alphaValue,...
                    'HitTest','on',...
                    'PickableParts','all');

                h.ButtonDownFcn = @(src,event) startDragElement(app,k);
                app.drawLockIcon(e);
            else

                h = rectangle(app.UIAxes,...
                    'Position',[e.x-s/2 e.y-s/2 s s],...
                    'EdgeColor',c,...
                    'LineStyle',lineStyle,...
                    'LineWidth',lw);

                h.ButtonDownFcn = @(src,event) startDragElement(app,k);

            end

        end

        function drawPinhole(app,e,k)

    isDisabled = isfield(e,'disabled') && e.disabled;
    isSelected = isequal(k,app.SelectedElement) || ...
                 ismember(k,app.SelectedElements);

    if isfield(e,'color')
        c = e.color;
    else
        c = 'm';
    end

    if isDisabled
        c = [0.6 0.6 0.6];
        lineStyle = '--';
        lw = 1.5;
    elseif isSelected
        lineStyle = '-';
        lw = 3;
    else
        lineStyle = '-';
        lw = 2;
    end

    r = e.diameter/2;

    theta = linspace(0,2*pi,100);
    x = e.x + r*cos(theta);
    y = e.y + r*sin(theta);

    % pinhole circle
    h = plot(app.UIAxes,x,y,...
        'Color',c,...
        'LineWidth',lw,...
        'LineStyle',lineStyle,...
        'HitTest','on',...
        'PickableParts','all');

    h.ButtonDownFcn = @(src,event) startDragElement(app,k);

    % dashed aperture/action plane
    planeLength = max(4*e.diameter, 40);

    t = [cosd(e.theta), sind(e.theta)];

    p1 = [e.x e.y] - planeLength/2*t;
    p2 = [e.x e.y] + planeLength/2*t;

    hPlane = plot(app.UIAxes,...
        [p1(1) p2(1)],...
        [p1(2) p2(2)],...
        '--',...
        'Color',c,...
        'LineWidth',1.2,...
        'HitTest','off',...
        'PickableParts','none');

    uistack(hPlane,'bottom')

    app.drawLockIcon(e);

end
        function drawPointSource(app,e,k)

            isDisabled = isfield(e,'disabled') && e.disabled;
            isSelected = isequal(k,app.SelectedElement) || ...
                ismember(k,app.SelectedElements);
            if isDisabled
                lineStyle = '--';
            else
                lineStyle = '-';
            end

            if isequal(k,app.SelectedElement) || ismember(k,app.SelectedElements)
                c = 'r';
                lw = 1.5;
                ms = 0.1;
            elseif isfield(e,'color')
                c = e.color;
                lw = 1.5;
                ms = 0.1;
            else
                c = 'g';
                lw = 1.5;
                ms = 0.1;
            end

            % source point
            h = plot(app.UIAxes,e.x,e.y,'o',...
                'Color',c,...
                'MarkerFaceColor',c,...
                'MarkerSize',ms,...
                'LineWidth',2,...
                'HitTest','on',...
                'PickableParts','all');

            h.ButtonDownFcn = @(src,event) startDragElement(app,k);


            % invisible clickable rays
            if isfield(e,'angleList')

                Lpick = 10000;

                for m = 1:length(e.angleList)

                    theta = e.theta + e.angleList(m);
                    
                    p1 = [e.x e.y];
                    p2 = p1 + Lpick*[cosd(theta), sind(theta)];

                    hPick = plot(app.UIAxes,...
                        [p1(1) p2(1)],...
                        [p1(2) p2(2)],...
                        '-',...
                        'Color','k',...
                        'LineWidth',1,...
                        'Visible','off',...
                        'HitTest','on',...
                        'PickableParts','all');

                    hPick.ButtonDownFcn = @(src,event) startDragElement(app,k);

                end

            end


            % visible short preview rays only after selected
            if isSelected && isfield(e,'angleList')

                L = 1;

                for m = 1:length(e.angleList)

                    theta = e.theta + e.angleList(m);
                    p1 = [e.x e.y];
                    p2 = p1 + L*[cosd(theta), sind(theta)];

                    hRay = plot(app.UIAxes,...
                        [p1(1) p2(1)],...
                        [p1(2) p2(2)],...
                        'Color',c,...
                        'LineStyle',lineStyle,...
                        'LineWidth',lw,...
                        'HitTest','on',...
                        'PickableParts','all');

                    hRay.ButtonDownFcn = @(src,event) startDragElement(app,k);

                end

            end

            app.drawLockIcon(e);
        end        %%%%%%%%Drawing END

        function drawSurface(app,e,k)
            isDisabled = isfield(e,'disabled') && e.disabled;

            isSelected = isequal(k,app.SelectedElement) || ...
                ismember(k,app.SelectedElements);

            if isfield(e,'color')
                c = e.color;
            else
                c = [0.2 0.2 0.2];
            end

            if isDisabled

                c = [0.6 0.6 0.6];
                lw = 2;

            elseif isSelected

                lw = 3;

            else

                lw = 2;

            end
            t = [cosd(e.theta), sind(e.theta)];
            p1 = [e.x e.y] - (e.D/2)*t;
            p2 = [e.x e.y] + (e.D/2)*t;
            h = plot(app.UIAxes,...
                [p1(1) p2(1)],...
                [p1(2) p2(2)],...
                '--',...
                'Color',c,...
                'LineWidth',lw,...
                'HitTest','on',...
                'PickableParts','all');

            h.ButtonDownFcn = @(src,event) startDragElement(app,k);
            app.drawLockIcon(e);
        end
        %%%%%%%%Drawing END

        function startDragElement(app,k)

            if strcmp(app.UIFigure.SelectionType,'alt')

                cp = app.UIAxes.CurrentPoint;

                x = cp(1,1);

                y = cp(1,2);

                app.CursorPositionLabel.Text = ...
                    sprintf('X = %.2f mm, Y = %.2f mm',x,y);

                drawnow

                return

            end

            if ~app.IsBoxSelecting

                app.SelectedElements = [];

            end

            if ~app.IsBoxSelecting
                app.SelectedElements = [];
            end

            app.SelectedMeasurement = [];
            if app.MeasureMode
                if isempty(app.MeasureStart)
                    app.MeasureStart = k;
                    app.TypeLabel.Text = 'Measure mode: select second element';
                else
                    m.k1 = app.MeasureStart;
                    m.k2 = k;
                    m.mode = app.getCurrentMeasurementMode();
                    app.Measurements{end+1} = m;
                    app.MeasureStart = [];
                    app.TypeLabel.Text = 'Measure mode: select first element';
                    drawScene(app)
                end
                return
            end

            if k > length(app.Elements)
                return
            end

            app.SelectedElement = k;
            app.SelectedElements = app.SelectedElements(app.SelectedElements <= length(app.Elements));

            app.updatePropertyPanel(k);
            drawScene(app)

            e = app.Elements{k};

            if ismember(k,app.SelectedElements)

                saveState(app)

                cp = app.UIAxes.CurrentPoint;
                app.GroupDragStartPoint = cp(1,1:2);

                app.GroupOriginalPositions = {};

                for i = 1:length(app.SelectedElements)
                    kk = app.SelectedElements(i);
                    ee = app.Elements{kk};
                    app.GroupOriginalPositions{i} = [ee.x ee.y];
                end

                app.UIFigure.WindowButtonMotionFcn = @(src,event) app.dragGroup();
                app.UIFigure.WindowButtonUpFcn = @(src,event) app.stopGroupDrag();

                return
            end

            cp = app.UIAxes.CurrentPoint;
            mousePos = cp(1,1:2);
            app.DragOffset = [e.x e.y] - mousePos;

            if isfield(e,'locked') && e.locked
                app.TypeLabel.Text = [app.TypeLabel.Text ' (Locked)'];
                return
            end

            saveState(app)

            app.IsDragging = true;
            app.UIFigure.WindowButtonMotionFcn = @(src,event) app.dragElement();
            app.UIFigure.WindowButtonUpFcn = @(src,event) app.stopDragElement();

        end

        function stopDragElement(app)

            app.IsDragging = false;

            % app.SelectedElement = [];

            app.UIFigure.WindowButtonMotionFcn = '';

            app.UIFigure.WindowButtonUpFcn = '';
        end

        function updateSelectedElementFromPanel(app)

            if isempty(app.SelectedElement)
                return
            end

            k = app.SelectedElement;
            e = app.Elements{k};

            e.x = str2double(app.XField.Value);
            e.y = str2double(app.YField.Value);
            e.theta = str2double(app.RotationAngleField.Value);

            sizeValue = str2double(app.Diameter.Value);

            switch e.type

                case 'Lens'
                    e.f = str2double(app.FocalLength.Value);
                    e.D = sizeValue;

                case 'Mirror'
                    e.D = sizeValue;

                case 'BS'
                    e.D = sizeValue;

                case 'Objective'
                    e.aperture = str2double(app.ObjectiveApertureField.Value);
                    e.f = str2double(app.ObjectiveFocalField.Value);
                    e.NA = str2double(app.ObjectiveNAField.Value);
                    % e.mag = str2double(app.ObjectiveMagField.Value);
                    e.length = str2double(app.ObjectiveLengthField.Value);
                    e.D = e.aperture;

                case 'Camera'
                    e.width = sizeValue;
                    e.height = sizeValue * 0.6;

                case 'Sample'
                    e.D = sizeValue;

                case 'Pinhole'
                    e.diameter = sizeValue;

            end

            app.Elements{k} = e;
            drawScene(app)

        end

        function dragElement(app)

            k = app.SelectedElement;
            e = app.Elements{k};

            if isfield(e,'locked') && e.locked
                return
            end

            if ~app.IsDragging
                return
            end

            cp = app.UIAxes.CurrentPoint;
            mousePos = cp(1,1:2);
            k = app.SelectedElement;
            e = app.Elements{k};
            newPos = mousePos + app.DragOffset;
            dragStep = 0.1;
            newPos = round(newPos/dragStep) * dragStep;
            e.x = newPos(1);
            e.y = newPos(2);

            app.Elements{k} = e;

            app.XField.Value = sprintf('%.2f',e.x);
            app.YField.Value = sprintf('%.2f',e.y);

            drawScene(app)

        end

        function traceRays(app)

             L = max(2000000, 1.2 * max(diff(app.CurrentXLim), diff(app.CurrentYLim)));
            maxBounces = 1000;
            minPower = 0.01;

            active_rays = {};

            % collect initial rays
            active_rays = {};

            for i = 1:length(app.Elements)

                elem = app.Elements{i};
                if isfield(elem,'disabled') && elem.disabled
                    continue
                end
                if strcmp(elem.type,'Ray')

                    dir = [cosd(elem.theta), sind(elem.theta)];
                    perp = [-dir(2), dir(1)];

                    beamWidth = elem.beamWidth;
                    rayCount = elem.rayCount;

                    offsets = linspace(-beamWidth/2, beamWidth/2, rayCount);

                    for m = 1:rayCount
                        r.pos = [elem.x, elem.y] + offsets(m)*perp;
                        r.dir = dir;
                        r.power = 1/rayCount;
                        if isfield(elem,'color')

                            r.color = elem.color;
                        else
                            r.color = 'g';
                        end
                        active_rays{end+1} = r;
                    end

                elseif strcmp(elem.type,'PointSource')

                    angles = elem.angleList;

                    for m = 1:length(angles)

                        r.pos = [elem.x, elem.y];

                        theta = elem.theta + angles(m);
                        r.dir = [cosd(theta), sind(theta)];

                        r.power = 1/length(angles);

                        if isfield(elem,'colorList') && length(elem.colorList) >= m

                            colorValue = elem.colorList{m};

                            if ischar(colorValue) || isstring(colorValue)

                                colorValue = char(colorValue);

                                if any(strcmp(colorValue,{'Red','Green','Blue','Cyan','Magenta','Yellow','Black'}))

                                    r.color = app.getColorCodeFromName(colorValue);

                                else

                                    r.color = colorValue;

                                end

                            else

                                r.color = colorValue;

                            end

                        elseif isfield(elem,'color')

                            r.color = elem.color;

                        else

                            r.color = 'g';

                        end
                        active_rays{end+1} = r;

                    end

                end

            end

            for bounce = 1:maxBounces

                new_rays = {};

                for rr = 1:length(active_rays)

                    ray = active_rays{rr};

                    if ray.power < minPower
                        continue
                    end

                    ray_pos = ray.pos;
                    ray_dir = ray.dir;

                    best_s = inf;
                    best_hit = [];
                    best_element = [];

                    for j = 1:length(app.Elements)

                        elem = app.Elements{j};
                        if isfield(elem,'disabled') && elem.disabled
                            continue
                        end
                        if ~(strcmp(elem.type,'Mirror') || strcmp(elem.type,'BS') || ...
                                strcmp(elem.type,'Pinhole') || strcmp(elem.type,'Camera') || ...
                                strcmp(elem.type,'Lens') || strcmp(elem.type,'Objective'))
                            continue
                        end

                        if strcmp(elem.type,'BS')

                            transDir = [cosd(elem.theta), sind(elem.theta)];
                            axisDir  = [cosd(elem.theta+90), sind(elem.theta+90)];

                            t = transDir - axisDir;
                            t = t/norm(t);

                        else

                            t = [cosd(elem.theta), sind(elem.theta)];

                        end

                        elem_pos = [elem.x, elem.y];

                        if strcmp(elem.type,'Objective')
                            elem_pos = [elem.x elem.y];
                        end
                        A = [ray_dir(:), -t(:)];
                        b = (elem_pos - ray_pos).';

                        if abs(det(A)) < 1e-9
                            continue
                        end

                        sol = A\b;
                        s = sol(1);
                        q = sol(2);

                        if strcmp(elem.type,'Pinhole')
                            elemLength = 500;
                        elseif strcmp(elem.type,'Camera')
                            elemLength = elem.width;
                        elseif strcmp(elem.type,'BS')
                            elemLength = elem.D *sqrt(2);
                        elseif isfield(elem,'D')
                            elemLength = elem.D;
                        else
                            elemLength = 40;
                        end

                        if s > 1e-6 && abs(q) <= elemLength/2
                            if s < best_s
                                best_s = s;
                                best_hit = ray_pos + s*ray_dir;
                                best_element = elem;
                            end
                        end

                    end

                    if isempty(best_element)

                        p2 = ray_pos + L*ray_dir;

                        if isfield(ray,'color')
                            rayColor = ray.color;
                        else
                            rayColor = 'g';
                        end

                        plot(app.UIAxes,...
                            [ray_pos(1), p2(1)],...
                            [ray_pos(2), p2(2)],...
                            '-',...
                            'Color',rayColor,...
                            'LineWidth',app.RayLineWidth,...
                            'HitTest','off',...
                            'PickableParts','none');

                        continue

                    end

                    hit = best_hit;

                    lineWidth = app.RayLineWidth * sqrt(ray.power);

                    if isfield(ray,'color')
                        rayColor = ray.color;
                    else
                        rayColor = 'g';
                    end

                    plot(app.UIAxes,...
                        [ray_pos(1), hit(1)],...
                        [ray_pos(2), hit(2)],...
                        '-',...
                        'Color',rayColor,...
                        'LineWidth',lineWidth,...
                        'HitTest','off',...
                        'PickableParts','none');

                    n = [cosd(best_element.theta+90), sind(best_element.theta+90)];

                    ref_dir = ray_dir - 2*dot(ray_dir,n)*n;
                    ref_dir = ref_dir/norm(ref_dir);

                    switch best_element.type

                        case 'Mirror'

                            r_new.pos = hit + 1e-6*ref_dir;
                            r_new.dir = ref_dir;
                            r_new.power = ray.power;
                            if isfield(ray,'color')

                                r_new.color = ray.color;

                            end
                            new_rays{end+1} = r_new;

                        case 'BS'
                            transDir = [cosd(best_element.theta), sind(best_element.theta)];
                            axisDir  = [cosd(best_element.theta+90), sind(best_element.theta+90)];

                            bs_t = transDir - axisDir;
                            bs_t = bs_t / norm(bs_t);

                            bs_n = [-bs_t(2), bs_t(1)];

                            ref_dir_BS = ray_dir - 2*dot(ray_dir,bs_n)*bs_n;
                            ref_dir_BS = ref_dir_BS / norm(ref_dir_BS);

                            if isfield(best_element,'R')
                                R = best_element.R;
                            else
                                R = 0.5;
                            end

                            if isfield(best_element,'T')
                                T = best_element.T;
                            else
                                T = 0.5;
                            end

                            r_ref.pos = hit + 1e-6*ref_dir_BS;
                            r_ref.dir = ref_dir_BS;
                            r_ref.power = ray.power * R;

                            r_trans.pos = hit + 1e-6*ray_dir;
                            r_trans.dir = ray_dir;
                            r_trans.power = ray.power * T;

                            if isfield(ray,'color')
                                r_ref.color = ray.color;
                                r_trans.color = ray.color;
                            end

                            new_rays{end+1} = r_ref;
                            new_rays{end+1} = r_trans;

                        case 'Pinhole'
                            center = [best_element.x, best_element.y];
                            radius = best_element.diameter / 2;
                            t_dir = [cosd(best_element.theta), sind(best_element.theta)];
                            dist = abs(dot(hit - center, t_dir));

                            if dist <= radius
                                r_new.pos = hit + 1e-6 * ray_dir;
                                r_new.dir = ray_dir;
                                r_new.power = ray.power;
                                if isfield(ray, 'color')
                                    r_new.color = ray.color;
                                end
                                new_rays{end+1} = r_new;
                            end

                        case 'Camera'
                            plot(app.UIAxes,...
                                hit(1), hit(2),...
                                'ro',...
                                'MarkerSize',5,...
                                'MarkerFaceColor','r');

                        case {'Lens','Objective'}

                            transDir = [cosd(best_element.theta), sind(best_element.theta)];

                            if strcmp(best_element.type,'Objective')
                                axis0 = [cosd(best_element.theta+90), sind(best_element.theta+90)];
                            else
                                axis0 = [cosd(best_element.theta+90), sind(best_element.theta+90)];
                            end

                            if dot(axis0, ray_dir) < 0
                                axisDir = -axis0;
                            else
                                axisDir = axis0;
                            end

                            center = [best_element.x, best_element.y];

                            if strcmp(best_element.type,'Objective')
                                fixedAxis = [cosd(best_element.theta+90), sind(best_element.theta+90)];
                                lens_center = [best_element.x best_element.y];
                            else
                                lens_center = center;
                            end
                            y = dot(hit - lens_center, transDir);

                            u_in = dot(ray_dir, transDir) / dot(ray_dir, axisDir);

                            u_out = u_in - y / best_element.f;

                            new_dir = axisDir + u_out * transDir;
                            new_dir = new_dir / norm(new_dir);

                            r_new.pos = hit + 1e-6 * new_dir;
                            r_new.dir = new_dir;
                            r_new.power = ray.power;

                            if isfield(ray,'color')
                                r_new.color = ray.color;
                            end

                            new_rays{end+1} = r_new;
                    end

                end

                if isempty(new_rays)
                    break
                end

                active_rays = new_rays;

            end

        end

        function AddPointSourceButtonPushed(app, event)

    app.AddPointSourceButtonPushed2(event);

       end

        function saveState(app)
            app.UndoStack{end+1} = app.Elements;

            if length(app.UndoStack) > app.MaxUndo

                app.UndoStack(1) = [];

            end

            app.RedoStack = {};

        end

        function name = makeDefaultName(app,type)

            count = 0;

            for i = 1:length(app.Elements)

                e = app.Elements{i};

                if strcmp(e.type,type)

                    count = count + 1;

                end

            end

            name = [type num2str(count+1)];

        end


        function name = getColorName(app,c)

            if isstring(c)
                c = char(c);
            end

            switch lower(c)
                case {'r','red'}
                    name = 'Red';
                case {'g','green'}
                    name = 'Green';
                case {'b','blue'}
                    name = 'Blue';
                case {'c','cyan'}
                    name = 'Cyan';
                case {'m','magenta'}
                    name = 'Magenta';
                case {'y','yellow'}
                    name = 'Yellow';
                case {'k','black'}
                    name = 'Black';
                otherwise
                    name = 'Green';
            end

        end

        function ensureExportFigureButton(app)

            if isempty(app.ExportFigureButton) || ~isvalid(app.ExportFigureButton)
                app.ExportFigureButton = uibutton(app.UIFigure, 'push');
                app.ExportFigureButton.ButtonPushedFcn = @(src,event) exportCurrentFigure(app);
                app.ExportFigureButton.BackgroundColor = [1 1 1];
                app.ExportFigureButton.FontWeight = 'bold';
                app.ExportFigureButton.Position = [1786 244 140 27];
                app.ExportFigureButton.Text = 'Export Figure';
            end

        end

        function exportCurrentFigure(app)

            [file,pathName] = uiputfile( ...
                {'*.png','PNG image (*.png)'; '*.pdf','PDF file (*.pdf)'}, ...
                'Export current layout figure');

            if isequal(file,0)
                return
            end

            outFile = fullfile(pathName,file);
            [~,~,ext] = fileparts(outFile);

            drawScene(app)
            drawnow

            switch lower(ext)
                case '.png'
                    exportgraphics(app.UIAxes,outFile,'Resolution',300);
                case '.pdf'
                    exportgraphics(app.UIAxes,outFile,'ContentType','vector');
                otherwise
                    outFile = [outFile '.png'];
                    exportgraphics(app.UIAxes,outFile,'Resolution',300);
            end

            app.TypeLabel.Text = ['Exported figure: ' outFile];

        end

        function ensureMeasureModeControl(app)

            if isempty(app.MeasureModeDropDown) || ~isvalid(app.MeasureModeDropDown)
                app.MeasureModeDropDown = uidropdown(app.UIFigure);
                app.MeasureModeDropDown.Items = {'Point-to-point', 'Surface-to-surface'};
                app.MeasureModeDropDown.Value = 'Surface-to-surface';
                app.MeasureModeDropDown.Position = [980 452 155 22];
                app.MeasureModeDropDown.ValueChangedFcn = @(src,event) drawScene(app);
            end

            app.MeasureModeDropDown.Visible = 'on';

        end

        function mode = getCurrentMeasurementMode(app)

            mode = 'Surface-to-surface';

            if ~isempty(app.MeasureModeDropDown) && isvalid(app.MeasureModeDropDown)
                mode = app.MeasureModeDropDown.Value;
            end

        end

        function surfaceX = getSurfaceMeasurementX(app,e)

            % Surface-to-surface mode measures only x-axis spacing.  A
            % vertical Surface element therefore contributes its x coordinate
            % only; its y position and drawn length do not affect dx.
            surfaceX = e.x;

        end

        function [p1,p2,d] = getMeasurementEndpoints(app,e1,e2,mode)

            c1 = [e1.x e1.y];
            c2 = [e2.x e2.y];

            if strcmp(mode,'Surface-to-surface')
                surfaceX1 = app.getSurfaceMeasurementX(e1);
                surfaceX2 = app.getSurfaceMeasurementX(e2);
                y = mean([c1(2), c2(2)]);

                % Draw a horizontal measurement line and report dx only.
                % This intentionally ignores all y differences.
                p1 = [surfaceX1 y];
                p2 = [surfaceX2 y];
                d = abs(surfaceX2 - surfaceX1);
            else
                % Point-to-point preserves the original diagonal center-to-center
                % Euclidean Measure behavior.
                p1 = c1;
                p2 = c2;
                d = norm(p2-p1);
            end

        end

        function drawMeasurements(app)

            if isempty(app.MeasureFirst) || isempty(app.SelectedElement)
                return
            end

            k1 = app.MeasureFirst;
            k2 = app.SelectedElement;

            if k1 == k2
                return
            end

            e1 = app.Elements{k1};
            e2 = app.Elements{k2};

            mode = app.getCurrentMeasurementMode();
            [p1,p2,d] = app.getMeasurementEndpoints(e1,e2,mode);

            plot(app.UIAxes,...
                [p1(1) p2(1)],...
                [p1(2) p2(2)],...
                'k--',...
                'LineWidth',1.5,...
                'HitTest','off');

            mid = (p1+p2)/2;

            if strcmp(mode,'Surface-to-surface')
                yTextOffset = 0.03 * diff(app.CurrentYLim);

                text(app.UIAxes,...
                    mid(1),mid(2)+yTextOffset,...
                    ['dx = ' num2str(d,'%.2f') ' mm'],...
                    'FontSize',12,...
                    'FontWeight','bold',...
                    'BackgroundColor','w',...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','bottom',...
                    'HitTest','off');

                dy = abs(e2.y - e1.y);
                xVert = mean([p1(1), p2(1)]);
                pY1 = [xVert e1.y];
                pY2 = [xVert e2.y];
                plot(app.UIAxes,...
                    [pY1(1) pY2(1)],...
                    [pY1(2) pY2(2)],...
                    'k:',...
                    'LineWidth',1.5,...
                    'HitTest','off');

                midY = (pY1+pY2)/2;
                yBottom = min([pY1(2), pY2(2)]);
                text(app.UIAxes,...
                    midY(1),yBottom-yTextOffset,...
                    ['dy = ' num2str(dy,'%.2f') ' mm'],...
                    'FontSize',12,...
                    'FontWeight','bold',...
                    'BackgroundColor','w',...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','top',...
                    'HitTest','off');
            else
                text(app.UIAxes,...
                    mid(1),mid(2),...
                    [num2str(d,'%.2f') ' mm'],...
                    'FontSize',12,...
                    'FontWeight','bold',...
                    'BackgroundColor','w',...
                    'HitTest','off');
            end

        end

        function selectMeasurement(app,i)

            if i < 1 || i > length(app.Measurements)
                app.SelectedMeasurement = [];
                return
            end

            app.SelectedMeasurement = i;
            app.SelectedElement = [];
            app.SelectedElements = [];
            app.TypeLabel.Text = sprintf('Selected measurement %d',i);

            drawScene(app)

        end

        function drawAllMeasurements(app)

            for i = 1:length(app.Measurements)

                m = app.Measurements{i};
                if m.k1 == m.k2
                    continue
                end

                if m.k1 > length(app.Elements) || m.k2 > length(app.Elements)
                    continue
                end

                e1 = app.Elements{m.k1};
                e2 = app.Elements{m.k2};

                mode = app.getCurrentMeasurementMode();
                [p1,p2,d] = app.getMeasurementEndpoints(e1,e2,mode);
                mid = (p1+p2)/2;
                yTextOffset = 0.03 * diff(app.CurrentYLim);

                if isequal(app.SelectedMeasurement,i)
                    measureColor = [1 0 0];
                    measureWidth = 2.0;
                else
                    measureColor = [0 0 0];
                    measureWidth = 1.2;
                end

                hMeasure = plot(app.UIAxes,...
                    [p1(1) p2(1)],...
                    [p1(2) p2(2)],...
                    '--',...
                    'Color',measureColor,...
                    'LineWidth',measureWidth,...
                    'HitTest','on',...
                    'PickableParts','all');
                hMeasure.ButtonDownFcn = @(src,event) selectMeasurement(app,i);

                if strcmp(mode,'Surface-to-surface')
                    labelText = ['dx = ' num2str(d,'%.1f') ' mm'];
                    dxLabelY = mid(2) + yTextOffset;
                else
                    labelText = [num2str(d,'%.1f') ' mm'];
                    dxLabelY = mid(2);
                end

                hText = text(app.UIAxes,...
                    mid(1),dxLabelY,...
                    labelText,...
                    'BackgroundColor','w',...
                    'FontWeight','bold',...
                    'Color',measureColor,...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','bottom',...
                    'HitTest','on',...
                    'PickableParts','all');
                hText.ButtonDownFcn = @(src,event) selectMeasurement(app,i);

                if strcmp(mode,'Surface-to-surface')
                    dy = abs(e2.y - e1.y);
                    xVert = mean([p1(1), p2(1)]);
                    pY1 = [xVert e1.y];
                    pY2 = [xVert e2.y];
                    midY = (pY1+pY2)/2;
                    yBottom = min([pY1(2), pY2(2)]);

                    hYMeasure = plot(app.UIAxes,...
                        [pY1(1) pY2(1)],...
                        [pY1(2) pY2(2)],...
                        ':',...
                        'Color',measureColor,...
                        'LineWidth',measureWidth,...
                        'HitTest','on',...
                        'PickableParts','all');
                    hYMeasure.ButtonDownFcn = @(src,event) selectMeasurement(app,i);

                    hYText = text(app.UIAxes,...
                        midY(1),yBottom-yTextOffset,...
                        ['dy = ' num2str(dy,'%.1f') ' mm'],...
                        'BackgroundColor','w',...
                        'FontWeight','bold',...
                        'Color',measureColor,...
                        'HorizontalAlignment','center',...
                        'VerticalAlignment','top',...
                        'HitTest','on',...
                        'PickableParts','all');
                    hYText.ButtonDownFcn = @(src,event) selectMeasurement(app,i);
                end

            end

        end

        function startBoxSelect(app)

            if ~app.IsBoxSelecting
                return
            end
            obj = hittest(app.UIFigure);

            if obj ~= app.UIAxes
                return
            end
            cp = app.UIAxes.CurrentPoint;
            app.BoxStartPoint = cp(1,1:2);

            app.UIFigure.WindowButtonMotionFcn = @(src,event) app.updateBoxSelect();
            app.UIFigure.WindowButtonUpFcn = @(src,event) app.finishBoxSelect();

        end

        function updateBoxSelect(app)

            cp = app.UIAxes.CurrentPoint;
            p2 = cp(1,1:2);
            p1 = app.BoxStartPoint;

            x = min(p1(1),p2(1));
            y = min(p1(2),p2(2));
            w = abs(p2(1)-p1(1));
            h = abs(p2(2)-p1(2));

            if isempty(app.SelectionBoxHandle) || ~isvalid(app.SelectionBoxHandle)
                app.SelectionBoxHandle = rectangle(app.UIAxes,...
                    'Position',[x y w h],...
                    'EdgeColor','r',...
                    'LineStyle','--',...
                    'LineWidth',1.5,...
                    'HitTest','off');
            else
                app.SelectionBoxHandle.Position = [x y w h];
            end

        end

        function finishBoxSelect(app)

            if isempty(app.SelectionBoxHandle) || ~isvalid(app.SelectionBoxHandle)
                app.UIFigure.WindowButtonMotionFcn = '';
                app.UIFigure.WindowButtonUpFcn = '';
                return
            end

            rect = app.SelectionBoxHandle.Position;

            x1 = rect(1);
            y1 = rect(2);
            x2 = x1 + rect(3);
            y2 = y1 + rect(4);

            app.SelectedElements = [];

            for k = 1:length(app.Elements)

                e = app.Elements{k};

                if e.x >= x1 && e.x <= x2 && e.y >= y1 && e.y <= y2
                    app.SelectedElements(end+1) = k;
                end

            end

            delete(app.SelectionBoxHandle)
            app.SelectionBoxHandle = [];

            app.UIFigure.WindowButtonMotionFcn = '';
            app.UIFigure.WindowButtonUpFcn = '';

            app.UIFigure.WindowButtonDownFcn = @(src,event) app.startBoxSelect();

            drawScene(app)

        end

        function dragGroup(app)

            cp = app.UIAxes.CurrentPoint;
            p = cp(1,1:2);

            delta = p - app.GroupDragStartPoint;
            dragStep = 0.1;
            delta = round(delta/dragStep) * dragStep;

            for i = 1:length(app.SelectedElements)

                k = app.SelectedElements(i);
                e = app.Elements{k};

                if isfield(e,'locked') && e.locked
                    continue
                end

                oldp = app.GroupOriginalPositions{i};

                e.x = oldp(1) + delta(1);
                e.y = oldp(2) + delta(2);

                app.Elements{k} = e;

            end
            drawScene(app)

        end

        function stopGroupDrag(app)

            app.UIFigure.WindowButtonMotionFcn = '';
            app.UIFigure.WindowButtonUpFcn = '';

        end

        function idx = getActiveSelection(app)

            if ~isempty(app.SelectedElements)
                idx = app.SelectedElements;
            elseif ~isempty(app.SelectedElement)
                idx = app.SelectedElement;
            else
                idx = [];
            end

        end

        function drawLockIcon(app,e)

            isLocked = isfield(e,'locked') && e.locked;

            if ~isLocked
                return
            end

            text(app.UIAxes,...
                e.x ,...
                e.y + 20,...
                '🔒',...
                'FontSize',14,...
                'FontWeight','bold',...
                'HitTest','off',...
                'PickableParts','none');

        end

        function drawBackgroundImage(app)
            imgPath = fullfile(fileparts(mfilename('fullpath')),'SampleNeeko.png');

            if ~isfile(imgPath)
                return
            end

            img = imread(imgPath);

            w = 50;

            x0 = app.CurrentXLim(2) - w - 50;
            y0 = app.CurrentYLim(1) + 50;
            [hImg,wImg,~] = size(img);
            aspect = wImg/hImg;
            h = w/aspect;

            image(app.UIAxes,...
                'XData',[x0 x0+w],...
                'YData',[y0+h y0],...
                'CData',img,...
                'AlphaData',1,...
                'HitTest','off',...
                'PickableParts','none');

        end

        function updateAxisLimits(app)

            app.CurrentXLim = [ ...
                app.XMinField.Value,...
                app.XMaxField.Value];

            app.CurrentYLim = [ ...
                app.YMinField.Value,...
                app.YMaxField.Value];

            xlim(app.UIAxes,app.CurrentXLim)
            ylim(app.UIAxes,app.CurrentYLim)
            app.syncAxisLimitFields();

        end

        function c = getColorCodeFromName(app,name)

            if iscell(name)
                name = name{1};
            end

            if isstring(name)
                name = char(name);
            end

            if iscategorical(name)
                name = char(name);
            end

            name = strtrim(char(name));

            switch lower(name)

                case {'red','r'}
                    c = 'r';

                case {'green','g'}
                    c = 'g';

                case {'blue','b'}
                    c = 'b';

                case {'cyan','c'}
                    c = 'c';

                case {'magenta','m'}
                    c = 'm';

                case {'yellow','y'}
                    c = 'y';

                case {'black','k'}
                    c = 'k';

                otherwise
                    disp(['Unknown color name: ' name])
                    c = 'g';

            end

        end
        function c = getElementColorCode(app,colorName)

            switch colorName

                case 'Gray'
                    c = [0.4 0.4 0.4];

                case 'Red'
                    c = 'r';

                case 'Green'
                    c = 'g';

                case 'Blue'
                    c = 'b';

                case 'Cyan'
                    c = 'c';

                case 'Magenta'
                    c = 'm';

                case 'Yellow'
                    c = 'y';

                case 'Black'
                    c = 'k';

                otherwise
                    c = [0.4 0.4 0.4];

            end

        end

        function updatePropertyPanel(app,k)

            if isempty(k) || k > length(app.Elements)
                return
            end

            e = app.Elements{k};

            if isfield(e,'color')
                app.ElementColorDropDown.Value = app.getColorName(e.color);
            else
                app.ElementColorDropDown.Value = 'Gray';
            end

            if isfield(e,'name')
                app.TypeLabel.Text = ['Selected: ' e.name];
                app.NameField.Value = e.name;
            else
                app.TypeLabel.Text = ['Selected: ' e.type];
                app.NameField.Value = '';
            end

            app.XField.Value = num2str(e.x);
            app.YField.Value = num2str(e.y);

            if isfield(e,'theta')
                app.RotationAngleField.Value = num2str(e.theta);
            else
                app.RotationAngleField.Value = '';
            end

            app.FocalLength.Value = '';
            app.Diameter.Value = '';

            switch e.type

                case 'Lens'
                    app.FocalLength.Value = num2str(e.f);
                    app.Diameter.Value = num2str(e.D);

                case {'Mirror','BS','Sample','Surface'}
                    app.Diameter.Value = num2str(e.D);

                case 'Camera'
                    app.Diameter.Value = num2str(e.width);

                case 'Pinhole'
                    app.Diameter.Value = num2str(e.diameter);

                case 'Ray'
                    app.RayAngleField.Value = num2str(e.theta);
                    app.RayWidthField.Value = num2str(e.beamWidth);
                    app.RayCountField.Value = e.rayCount;
                    colorName = app.getColorName(e.color);

                    if any(strcmp(colorName, app.RayColorDropDown.Items))

                        app.RayColorDropDown.Value = colorName;

                    else

                        app.RayColorDropDown.Value = app.RayColorDropDown.Items{1};

                    end
                case 'PointSource'
                    if isfield(e,'angleList') && isfield(e,'colorList')
                        colors = cell(size(e.colorList));

                        for i = 1:length(e.colorList)
                            colors{i} = app.getColorName(e.colorList{i});
                        end

                        app.PointRayTable.Data = [num2cell(e.angleList(:)), colors(:)];
                        app.PointSourceRangeField.Value = max(abs(e.angleList));
                        app.PointSourceRayCountField.Value = length(e.angleList);
                    end

                case 'Objective'
                    app.ObjectiveApertureField.Value = num2str(e.aperture);
                    app.ObjectiveFocalField.Value = num2str(e.f);
                    app.ObjectiveNAField.Value = num2str(e.NA);
                    % app.ObjectiveMagField.Value = num2str(e.mag);
                    app.ObjectiveLengthField.Value = num2str(e.length);

                    app.FocalLength.Value = num2str(e.f);
                    app.Diameter.Value = num2str(e.aperture);

                    if isfield(e,'angleRange')
                        app.ObjectiveAngleRangeField.Value = num2str(e.angleRange,'%.2f');
                    else
                        app.ObjectiveAngleRangeField.Value = num2str(asind(e.NA),'%.2f');
                    end
            end

        end

        function scrollZoom(app,event)

            if ~app.isSwitchOn(app.ZoomSwitch)
                return
            end

            cp = app.UIAxes.CurrentPoint;
            cx = cp(1,1);
            cy = cp(1,2);

            xlim0 = app.UIAxes.XLim;
            ylim0 = app.UIAxes.YLim;


            if cx < xlim0(1) || cx > xlim0(2) || cy < ylim0(1) || cy > ylim0(2)
                return
            end

            factor = 1.2;

            if event.VerticalScrollCount > 0
                scale = factor;      % zoom out
            else
                scale = 1/factor;    % zoom in
            end

            app.CurrentXLim = cx + (xlim0 - cx)*scale;
            app.CurrentYLim = cy + (ylim0 - cy)*scale;

            xlim(app.UIAxes,app.CurrentXLim)
            ylim(app.UIAxes,app.CurrentYLim)
            app.syncAxisLimitFields();

        end

        function success = saveLayout(app)

            success = false;

            layout.elements = app.Elements;
            layout.measurements = app.Measurements;
            layout.currentXLim = app.CurrentXLim;
            layout.currentYLim = app.CurrentYLim;

            [file,path] = uiputfile('*.optic','Save optical layout');

            if isequal(file,0)
                return
            end

            save(fullfile(path,file),'layout');

            success = true;

        end

        function syncAxisLimitFields(app)

            app.XMinField.Value = app.CurrentXLim(1);
            app.XMaxField.Value = app.CurrentXLim(2);

            app.YMinField.Value = app.CurrentYLim(1);
            app.YMaxField.Value = app.CurrentYLim(2);

        end

        function tf = isSwitchOn(app,sw)
            value = string(sw.Value);
            tf = strcmp(value,'On') || strcmp(value,"  ");
        end

        function handleKeyPress(app,event)
            obj = app.UIFigure.CurrentObject;

            if isa(obj,'matlab.ui.control.EditField') || ...
                    isa(obj,'matlab.ui.control.NumericEditField') || ...
                    isa(obj,'matlab.ui.control.TextArea')
                return
            end
            key = event.Key;
            mod = event.Modifier;

            isCmd  = any(strcmp(mod,'command'));   % Mac
            isCtrl = any(strcmp(mod,'control'));   % Windows
            isShortcut = isCmd || isCtrl;

            if isShortcut && strcmp(key,'c')
                app.copyToClipboard();
                return
            end

            if isShortcut && strcmp(key,'v')
                app.pasteFromClipboard();
                return
            end

            if isShortcut && strcmp(key,'z')
                app.UndoButtonPushed([]);
                return
            end

            if isShortcut && strcmp(key,'y')
                app.RedoButtonPushed([]);
                return
            end

            if strcmp(key,'delete') || strcmp(key,'backspace')
                app.DeleteSelectedButtonPushed([]);
                return
            end

            step = 0.1;

            if any(strcmp(event.Modifier,'shift'))
                step = 1;
            end

            switch event.Key

                case 'leftarrow'
                    app.moveSelectedBy(-step,0);
                    return

                case 'rightarrow'
                    app.moveSelectedBy(step,0);
                    return

                case 'uparrow'
                    app.moveSelectedBy(0,step);
                    return

                case 'downarrow'
                    app.moveSelectedBy(0,-step);
                    return

            end


        end

        function copyToClipboard(app)

            idx = app.getActiveSelection();

            if isempty(idx)
                return
            end

            app.ClipboardElements = app.Elements(idx);

            xy = [];
            for i = 1:length(app.ClipboardElements)
                e = app.ClipboardElements{i};
                if isfield(e,'x') && isfield(e,'y')
                    xy(end+1,:) = [e.x e.y];
                end
            end

            if isempty(xy)
                app.ClipboardAnchor = [];
            else
                % Store the copied group center so paste applies one shared
                % offset and preserves every element's relative coordinates.
                app.ClipboardAnchor = mean(xy,1);
            end

        end

        function pasteFromClipboard(app)

            if isempty(app.ClipboardElements)
                return
            end

            saveState(app)

            newIdx = [];

            target = [mean(app.CurrentXLim), mean(app.CurrentYLim)];

            if isempty(app.ClipboardAnchor)
                offset = [0 0];
            else
                offset = target - app.ClipboardAnchor;
            end

            for i = 1:length(app.ClipboardElements)

                e2 = app.ClipboardElements{i};

                if isfield(e2,'x') && isfield(e2,'y')
                    e2.x = e2.x + offset(1);
                    e2.y = e2.y + offset(2);
                end

                if isfield(e2,'type')
                    e2.name = makeDefaultName(app,e2.type);
                end

                app.Elements{end+1} = e2;
                newIdx(end+1) = length(app.Elements);

            end

            app.SelectedElements = newIdx;
            app.SelectedElement = [];

            drawScene(app)
        end

        function showRightClickCoordinate(app)


            if strcmp(app.UIFigure.SelectionType,'open')
                app.SelectedElement = [];
                app.SelectedElements = [];
                app.TypeLabel.Text = 'No element selected';
                drawScene(app)
                return
            end

            if strcmp(app.UIFigure.SelectionType,'alt')
                cp = app.UIAxes.CurrentPoint;
                x = cp(1,1);
                y = cp(1,2);

                app.CursorPositionLabel.Text = ...
                    sprintf('X = %.2f mm, Y = %.2f mm',x,y);
                return
            end

if strcmp(app.UIFigure.SelectionType,'normal')

    if app.ViewLocked
        return
    end

    app.IsPanning = true;

    cp = app.UIAxes.CurrentPoint;

    app.PanStartPoint = cp(1,1:2);

    app.PanStartXLim = app.CurrentXLim;
    app.PanStartYLim = app.CurrentYLim;

    app.UIFigure.WindowButtonMotionFcn = ...
        @(src,event) app.panCanvas();

    app.UIFigure.WindowButtonUpFcn = ...
        @(src,event) app.stopPanCanvas();

end
        end
        function moveSelectedBy(app,dx,dy)

            idx = app.getActiveSelection();

            if isempty(idx)
                return
            end

            saveState(app)

            for i = 1:length(idx)

                k = idx(i);
                e = app.Elements{k};

                if isfield(e,'locked') && e.locked
                    continue
                end

                if isfield(e,'x')
                    e.x = e.x + dx;
                end

                if isfield(e,'y')
                    e.y = e.y + dy;
                end

                app.Elements{k} = e;

            end

            if ~isempty(app.SelectedElement)
                app.updatePropertyPanel(app.SelectedElement);
            end

            drawScene(app)

        end

        function code = getColorCode(app,name)

            if isstring(name)

                name = char(name);

            end

            switch lower(name)

                case {'red','r'}

                    code = 'r';

                case {'green','g'}

                    code = 'g';

                case {'blue','b'}

                    code = 'b';

                case {'cyan','c'}

                    code = 'c';

                case {'magenta','m'}

                    code = 'm';

                case {'yellow','y'}

                    code = 'y';

                case {'black','k'}

                    code = 'k';

                otherwise

                    code = 'g';

            end

        end

        function updateSelectedPointSourceAngles(app)

            if isempty(app.SelectedElement)
                return
            end

            k = app.SelectedElement;
            e = app.Elements{k};

            if ~strcmp(e.type,'PointSource')
                return
            end

            rangeAngle = app.PointSourceRangeField.Value;
            rayCount = round(app.PointSourceRayCountField.Value);

            if isnan(rangeAngle) || rangeAngle < 0
                return
            end

            if isnan(rayCount) || rayCount < 1
                return
            end

            % if mod(rayCount,2) == 0
            %     rayCount = rayCount + 1;
            %     app.PointSourceRayCountField.Value = rayCount;
            % end

            saveState(app)

            e.angleList = linspace(-rangeAngle,rangeAngle,rayCount);

            defaultColors = {'r','g','b','c','m','y','k'};
            e.colorList = cell(rayCount,1);

            for i = 1:rayCount
                e.colorList{i} = defaultColors{mod(i-1,length(defaultColors))+1};
            end

            app.Elements{k} = e;

            app.updatePropertyPanel(k);
            drawScene(app)

        end


        function panCanvas(app)

            if ~app.IsPanning
                return
            end

            cp = app.UIAxes.CurrentPoint;
            currentPoint = cp(1,1:2);

            delta = currentPoint - app.PanStartPoint;

            app.CurrentXLim = app.PanStartXLim - delta(1);
            app.CurrentYLim = app.PanStartYLim - delta(2);

            xlim(app.UIAxes,app.CurrentXLim)
            ylim(app.UIAxes,app.CurrentYLim)

            app.syncAxisLimitFields();

        end

        function stopPanCanvas(app)

            app.IsPanning = false;

            app.UIFigure.WindowButtonMotionFcn = '';
            app.UIFigure.WindowButtonUpFcn = '';

        end


        function pos = getElementReferencePosition(app,e)

    if strcmp(e.type,'Objective')
        axisDir = [cosd(e.theta+90), sind(e.theta+90)];
        pos = [e.x e.y] + e.f * axisDir;   % BFP position
    else
        pos = [e.x e.y];
    end

end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            disableDefaultInteractivity(app.UIAxes)
            app.XMinField.ValueDisplayFormat = '%.2f';
            app.XMaxField.ValueDisplayFormat = '%.2f';
            app.YMinField.ValueDisplayFormat = '%.2f';
            app.YMaxField.ValueDisplayFormat = '%.2f';
            app.UIAxes.Toolbar.Visible = 'off';
            app.CurrentXLim = [0 3000];
            app.CurrentYLim = [0 1200];
            xlabel(app.UIAxes,'X (mm)')
            ylabel(app.UIAxes,'Y (mm)')
            title(app.UIAxes,'Layout')
            app.ZoomLamp.Color = [0.7 0.7 0.7];
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.PointRayTable,s);
            app.MeasureLamp.Color = [0.7 0.7 0.7];
            xlim(app.UIAxes,app.CurrentXLim)
            ylim(app.UIAxes,app.CurrentYLim)
            grid(app.UIAxes,'on')
            app.BoxSelectLamp.Color = [0.7 0.7 0.7];
            disp('startup finished')
            drawScene(app)
            app.XMinField.Value = 0;
            app.XMaxField.Value = 3000;
            app.YMinField.Value = 0;
            app.YMaxField.Value = 1200;
            app.drawBackgroundImage();
            drawnow
            app.PointRayTable.ColumnName = {'Angle','Color'};
            app.PointRayTable.ColumnEditable = [true true];
            app.PointRayTable.ColumnFormat = { ...
                'numeric', ...
                {'Red','Green','Blue','Cyan','Magenta','Yellow','Black'} ...
                };
            app.UIFigure.WindowScrollWheelFcn = @(src,event) app.scrollZoom(event);
            app.LockSelectedButton.Tooltip = 'Locked selected element(s) cannot be moved.';
            app.DisableSelectedButton.Tooltip = 'Disabled element(s) are visible but do not affect the optical path.';
            app.AddRayButton.Tooltip = 'Add a parallel beam.';
            app.AddPointSourceButton.Tooltip = 'Add a point source.';
            app.AddObjectiveButton.Tooltip = 'Adjust the incident beam direction according to the front focal plane.';
            app.AddSampleButton.Tooltip = 'Click here if you want to see my cat.';
            app.AddBSButton.Tooltip = 'Default R:T = 50:50.';
            app.UIFigure.WindowKeyPressFcn = @(src,event) app.handleKeyPress(event);
            app.UIAxes.ButtonDownFcn = @(src,event) app.showRightClickCoordinate();
            app.CursorPositionLabel.Tooltip = ...
                'Right click anywhere in the layout to display the current coordinate.';
            app.ViewLockLamp.Color = [0.7 0.7 0.7];
            % app.UIFigure.WindowButtonDownFcn = @(src,event) startBoxSelect(app);
        end

        % Button pushed function: AddLensButton
        function AddLensButtonPushed2(app, event)
            saveState(app)

            e.type = 'Lens';
            e.name = makeDefaultName(app,e.type);
            cx = mean(app.CurrentXLim);
            cy = mean(app.CurrentYLim);
            offset = 10 * mod(length(app.Elements),5);
            e.x = cx + offset;
            e.y = cy + offset;
            e.theta = 90;
            f0 = str2double(app.FocalLength.Value);

if isnan(f0)
    f0 = 100;
end

e.f = f0;
            e.D = 50;
            app.Elements{end+1} = e;
            drawScene(app)
        end

        % Button pushed function: AddMirrorsButton
        function AddMirrorsButtonPushed(app, event)
            saveState(app)
            e.type = 'Mirror';
            e.name = makeDefaultName(app,e.type);
            cx = mean(app.CurrentXLim);
            cy = mean(app.CurrentYLim);
            offset = 10*(mod(length(app.Elements),5));
            e.x = cx + offset;
            e.y = cy + offset;
            e.theta = 45;
            e.D = 25.4;
            app.Elements{end+1} = e;
            drawScene(app)
        end

        % Button pushed function: AddBSButton
        function AddBSButtonPushed(app, event)
            saveState(app)
            e.type = 'BS';
            e.name = makeDefaultName(app,e.type);
            cx = mean(app.CurrentXLim);
            cy = mean(app.CurrentYLim);
            offset = 10*(mod(length(app.Elements),5));
            e.x = cx + offset;
            e.y = cy + offset;
            e.theta = 0;
            e.R = 0.5;
            e.T = 0.5;
            e.D = 25.4;
            app.Elements{end+1} = e;
            drawScene(app)
        end

        % Value changed function: RotationAngleField
        function RotationAngleFieldValueChanged(app, event)
            newTheta = str2double(app.RotationAngleField.Value);

            if isnan(newTheta)
                return
            end

            if ~isempty(app.SelectedElements)

                saveState(app)

                idx = app.SelectedElements(app.SelectedElements <= length(app.Elements));
                xy = [];
                thetaRef = [];

                for i = 1:length(idx)
                    e = app.Elements{idx(i)};

                    if isfield(e,'x') && isfield(e,'y')
                        xy(end+1,:) = [e.x e.y];
                    end

                    if isempty(thetaRef) && isfield(e,'theta')
                        thetaRef = e.theta;
                    end
                end

                if isempty(thetaRef)
                    thetaRef = 0;
                end

                if isempty(xy)
                    groupCenter = [0 0];
                else
                    % Rotate the whole box-selected group around the bounding
                    % box center, preserving each element's relative position.
                    groupCenter = [(min(xy(:,1))+max(xy(:,1)))/2, ...
                                   (min(xy(:,2))+max(xy(:,2)))/2];
                end

                deltaTheta = newTheta - thetaRef;
                R = [cosd(deltaTheta) -sind(deltaTheta); ...
                     sind(deltaTheta)  cosd(deltaTheta)];

                for i = 1:length(idx)
                    k = idx(i);
                    e = app.Elements{k};

                    if isfield(e,'x') && isfield(e,'y')
                        p = ([e.x e.y] - groupCenter) * R.' + groupCenter;
                        e.x = p(1);
                        e.y = p(2);
                    end

                    if isfield(e,'theta')
                        e.theta = e.theta + deltaTheta;
                    end

                    app.Elements{k} = e;
                end

            elseif ~isempty(app.SelectedElement)

                saveState(app)

                k = app.SelectedElement;
                e = app.Elements{k};

                if isfield(e,'theta')
                    e.theta = newTheta;
                    app.Elements{k} = e;
                end

            end

            drawScene(app)
        end

        % Value changed function: XField
        function XFieldValueChanged(app, event)
            saveState(app)
            value = app.XField.Value;
            updateSelectedElementFromPanel(app)
        end

        % Value changed function: YField
        function YFieldValueChanged(app, event)
            saveState(app)
            value = app.YField.Value;
            updateSelectedElementFromPanel(app)
        end

        % Value changed function: FocalLength
        function FocalLengthValueChanged(app, event)
            saveState(app)
            value = app.FocalLength.Value;
            updateSelectedElementFromPanel(app)
        end

        % Value changed function: Diameter
        function DiameterValueChanged(app, event)
            newD = str2double(app.Diameter.Value);

            if isnan(newD)
                return
            end

            if ~isempty(app.SelectedElements)

                saveState(app)

                for i = 1:length(app.SelectedElements)

                    k = app.SelectedElements(i);
                    e = app.Elements{k};

                    if isfield(e,'D')
                        e.D = newD;
                    end

                    if strcmp(e.type,'Objective')
                        e.aperture = newD;
                        e.D = newD;
                    elseif strcmp(e.type,'Pinhole')
                        e.diameter = newD;
                    elseif strcmp(e.type,'Camera')
                        e.width = newD;
                    end

                    app.Elements{k} = e;

                end

            elseif ~isempty(app.SelectedElement)

                saveState(app)

                k = app.SelectedElement;
                e = app.Elements{k};

                if isfield(e,'D')
                    e.D = newD;
                end

                if strcmp(e.type,'Objective')
                    e.aperture = newD;
                    e.D = newD;
                elseif strcmp(e.type,'Pinhole')
                    e.diameter = newD;
                elseif strcmp(e.type,'Camera')
                    e.width = newD;
                end

                app.Elements{k} = e;

            end

            drawScene(app)


        end

        % Button pushed function: DeleteSelectedButton
        function DeleteSelectedButtonPushed(app, event)
            if ~isempty(app.SelectedMeasurement)
                if app.SelectedMeasurement >= 1 && app.SelectedMeasurement <= length(app.Measurements)
                    saveState(app)
                    app.Measurements(app.SelectedMeasurement) = [];
                end

                app.SelectedMeasurement = [];
                drawScene(app)
                return
            end

            idx = app.getActiveSelection();

            if isempty(idx)
                return
            end

            saveState(app)

            idx = sort(idx,'descend');

            for i = 1:length(idx)
                app.Elements(idx(i)) = [];
            end

            app.SelectedElement = [];
            app.SelectedElements = [];

            drawScene(app)
        end

        % Button pushed function: SaveLayoutButton
        function SaveLayoutButtonPushed(app, event)
            app.saveLayout();
        end

        % Button pushed function: LoadLayoutButton
        function LoadLayoutButtonPushed(app, event)
            [file,path] = uigetfile('*.optic','Load optical layout');

            if isequal(file,0)
                return
            end

            S = load(fullfile(path,file),'-mat');

            if isfield(S,'layout')
                app.Elements = S.layout.elements;

                if isfield(S.layout,'measurements')
                    app.Measurements = S.layout.measurements;
                end

                if isfield(S.layout,'currentXLim')
                    app.CurrentXLim = S.layout.currentXLim;
                end

                if isfield(S.layout,'currentYLim')
                    app.CurrentYLim = S.layout.currentYLim;
                end

            elseif isfield(S,'elements')
                app.Elements = S.elements;
            end

            drawScene(app)
        end

        % Button pushed function: AddRayButton
        function AddRayButtonPushed(app, event)

    saveState(app)

    e.type = 'Ray';
    e.name = makeDefaultName(app,e.type);

    e.x = mean(app.CurrentXLim);
    e.y = mean(app.CurrentYLim);

    theta0 = str2double(app.RayAngleField.Value);
    if isnan(theta0)
        theta0 = 0;
    end
    e.theta = theta0;

    width0 = str2double(app.RayWidthField.Value);
    if isnan(width0) || width0 <= 0
        width0 = 30;
    end
    e.beamWidth = width0;

    if isfield(app,'RayColorDropDown')
        e.color = app.getColorCodeFromName(app.RayColorDropDown.Value);
    else
        e.color = 'g';
    end

    rayCount0 = round(app.RayCountField.Value);

if isnan(rayCount0) || rayCount0 < 1

    rayCount0 = 5;

end

e.rayCount = rayCount0;

    e.power = 1;

    app.Elements{end+1} = e;
    app.SelectedElement = length(app.Elements);

    app.updatePropertyPanel(app.SelectedElement);
    drawScene(app)

        end

        % Button pushed function: AddObjectiveButton
        function AddObjectiveButtonPushed(app, event)
            saveState(app)
            e.type = 'Objective';
            e.name = makeDefaultName(app,e.type);
            cx = mean(app.CurrentXLim);
            cy = mean(app.CurrentYLim);
            offset = 10*(mod(length(app.Elements),5));
            e.x = cx + offset;
            e.y = cy + offset;
            e.theta = 90;


e.aperture = str2double(app.ObjectiveApertureField.Value);

e.length = str2double(app.ObjectiveLengthField.Value);

e.f = str2double(app.ObjectiveFocalField.Value);

e.NA = str2double(app.ObjectiveNAField.Value);
% e.angleRange = asind(e.NA);

if isnan(e.aperture)
    e.aperture = 25;
end

if isnan(e.length)
    e.length = 40;
end

if isnan(e.f)
    e.f = 20;
end

if isnan(e.NA)
    e.NA = 0.75;
end
          e.angleRange = asind(e.NA);
            e.D = e.aperture;
            app.Elements{end+1} = e;
           
app.SelectedElement = length(app.Elements);

app.updatePropertyPanel(app.SelectedElement);
drawScene(app)
        end

        % Button pushed function: AddCameraButton
        function AddCameraButtonPushed(app, event)
            saveState(app)
            e.type = 'Camera';
            e.name = makeDefaultName(app,e.type);
            cx = mean(app.CurrentXLim);
            cy = mean(app.CurrentYLim);
            offset = 10*(mod(length(app.Elements),5));
            e.x = cx + offset;
            e.y = cy + offset;
            e.theta = 90;
            e.width = 40;
            e.height = 25;
            app.Elements{end+1} = e;
            drawScene(app)
        end

        % Button pushed function: AddSampleButton
        function AddSampleButtonPushed(app, event)
            saveState(app)
            e.type = 'Sample';
            e.name = makeDefaultName(app,e.type);
            cx = mean(app.CurrentXLim);
            cy = mean(app.CurrentYLim);
            offset = 10*(mod(length(app.Elements),5));
            e.x = cx + offset;
            e.y = cy + offset;
            e.theta = 0;
            e.D = 25.4;
            e.imagePath = fullfile(fileparts(mfilename('fullpath')),'SampleNeeko.png');
            app.Elements{end+1} = e;
            drawScene(app)
        end

        % Button pushed function: AddPinholeButton
        function AddPinholeButtonPushed(app, event)
            saveState(app)
            e.type = 'Pinhole';
            e.name = makeDefaultName(app,e.type);
            cx = mean(app.CurrentXLim);
            cy = mean(app.CurrentYLim);
            offset = 10*(mod(length(app.Elements),5));
            e.x = cx + offset;
            e.y = cy + offset;
            e.theta = 90;
            e.diameter = 10;
            app.Elements{end+1} = e;
            drawScene(app)
        end

        % Value changed function: RayAngleField
        function RayAngleFieldButtonPushed(app, event)
            if isempty(app.SelectedElement)
                return
            end

            k = app.SelectedElement;

            e = app.Elements{k};

            if ~strcmp(e.type,'Ray')
                return
            end
            saveState(app)
            e.theta = str2double(app.RayAngleField.Value);

            app.Elements{k} = e;

            drawScene(app)
        end

        % Value changed function: RayWidthField
        function RayWidthFieldValueChanged(app, event)
            value = str2double(app.RayWidthField.Value);

            if isempty(app.SelectedElement)
                return
            end

            k = app.SelectedElement;
            e = app.Elements{k};

            if ~strcmp(e.type,'Ray')
                return
            end
            saveState(app)
            e.beamWidth = str2double(app.RayWidthField.Value);

            app.Elements{k} = e;

            drawScene(app)

        end

        % Value changed function: ObjectiveApertureField
        function ObjectiveApertureFieldValueChanged(app, event)
            updateSelectedElementFromPanel(app)
        end

        % Value changed function: ObjectiveFocalField
        function ObjectiveFocalFieldValueChanged(app, event)
            updateSelectedElementFromPanel(app)
        end

        % Value changed function: ObjectiveNAField
        function ObjectiveNAFieldValueChanged(app, event)
            if isempty(app.SelectedElement)
                return
            end

            k = app.SelectedElement;
            e = app.Elements{k};

            if ~strcmp(e.type,'Objective')
                return
            end

            newNA = str2double(app.ObjectiveNAField.Value);

            if isnan(newNA)
                return
            end

            if newNA < 0
                newNA = 0;
            elseif newNA > 1
                newNA = 1;
            end

            saveState(app)

            e.NA = newNA;
            e.angleRange = asind(newNA);

            app.ObjectiveNAField.Value = num2str(e.NA);
            app.ObjectiveAngleRangeField.Value = num2str(e.angleRange,'%.2f');

            app.Elements{k} = e;

            drawScene(app)
        end

        % Value changed function: ObjectiveLengthField
        function ObjectiveLengthFieldValueChanged(app, event)
            updateSelectedElementFromPanel(app)
        end

        % Button pushed function: AddPointSourceButton
        function AddPointSourceButtonPushed2(app, event)
 saveState(app)

            e.type = 'PointSource';

            e.name = makeDefaultName(app,e.type);

e.x = mean(app.CurrentXLim);

e.y = mean(app.CurrentYLim);
            e.theta = 0;

            rangeAngle = app.PointSourceRangeField.Value;
            rayCount = round(app.PointSourceRayCountField.Value);

            if isnan(rangeAngle) || rangeAngle < 0
                rangeAngle = 40;
            end

            if isnan(rayCount) || rayCount < 1
                rayCount = 1;
            end

            e.angleList = linspace(-rangeAngle, rangeAngle, rayCount);

            defaultColors = {'r','g','b','c','m','y','k'};

            e.colorList = cell(rayCount,1);

            for i = 1:rayCount
                e.colorList{i} = defaultColors{mod(i-1,length(defaultColors))+1};
            end
            e.rayCount = length(e.angleList);

            e.color = 'g';

            e.power = 1;

            app.Elements{end+1} = e;

            app.SelectedElement = length(app.Elements);

            app.PointRayTable.Data = [ ...

            num2cell(e.angleList(:)), ...

            e.colorList(:) ...

            ];
            app.SelectedElement = length(app.Elements);
            app.updatePropertyPanel(app.SelectedElement);
            drawScene(app)

        end

        % Button pushed function: LockSelectedButton
        function LockSelectedButtonPushed(app, event)
            idx = app.getActiveSelection();

            if isempty(idx)
                return
            end

            saveState(app)

            for i = 1:length(idx)
                k = idx(i);
                e = app.Elements{k};
                e.locked = true;
                app.Elements{k} = e;
            end

            drawScene(app)
        end

        % Button pushed function: UnlockSelectedButton
        function UnlockSelectedButtonPushed(app, event)
            idx = app.getActiveSelection();

            if isempty(idx)
                return
            end

            saveState(app)

            for i = 1:length(idx)
                k = idx(i);
                e = app.Elements{k};
                e.locked = false;
                app.Elements{k} = e;
            end

            drawScene(app)
        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)
            app.CurrentXLim = [0 3000];
            app.CurrentYLim = [0 1200];
            drawScene(app)
        end

        % Value changed function: ZoomSwitch
        function ZoomSwitchValueChanged(app, event)
            if app.isSwitchOn(app.ZoomSwitch)
                app.ZoomLamp.Color = [0 1 0];
                zoom(app.UIFigure,'off');
                pan(app.UIFigure,'off');
            else
                app.ZoomLamp.Color = [0.7 0.7 0.7];
                zoom(app.UIFigure,'off');
                pan(app.UIFigure,'off');
            end

        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            saveState(app)

            app.Elements = {};

            app.SelectedElement = [];

            drawScene(app)
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if isempty(app.UndoStack)

                return

            end

            app.RedoStack{end+1} = app.Elements;

            app.Elements = app.UndoStack{end};

            app.UndoStack(end) = [];

            app.SelectedElement = [];

            drawScene(app)
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            if isempty(app.RedoStack)

                return

            end

            app.UndoStack{end+1} = app.Elements;

            app.Elements = app.RedoStack{end};

            app.RedoStack(end) = [];

            app.SelectedElement = [];

            drawScene(app)
        end

        % Value changed function: NameField
        function NameFieldValueChanged(app, event)
            if isempty(app.SelectedElement)

                return

            end

            saveState(app)

            k = app.SelectedElement;

            e = app.Elements{k};

            e.name = app.NameField.Value;

            app.Elements{k} = e;

            app.TypeLabel.Text = ['Selected: ' e.name];

            drawScene(app)

        end

        % Cell edit callback: PointRayTable
        function PointRayTableCellEdit(app, event)
            if isempty(app.SelectedElement)

                return

            end

            k = app.SelectedElement;

            e = app.Elements{k};

            if ~strcmp(e.type,'PointSource')

                return

            end

            saveState(app)

            data = app.PointRayTable.Data;

            angles = cell2mat(data(:,1));
            colorNames = data(:,2);

            e.angleList = angles(:).';
            e.colorList = cell(size(colorNames));

            for i = 1:length(colorNames)
                e.colorList{i} = app.getColorCode(colorNames{i});
            end

            app.Elements{k} = e;
            drawScene(app)
        end

        % Value changed function: RayColorDropDown
        function RayColorDropDownValueChanged(app, event)
            if isempty(app.SelectedElement)

                return

            end

            k = app.SelectedElement;

            e = app.Elements{k};

            if ~strcmp(e.type,'Ray')

                return

            end

            saveState(app)

            e.color = app.getColorCode(app.RayColorDropDown.Value);

            app.Elements{k} = e;

            drawScene(app)
        end

        % Button pushed function: CopySelectedButton
        function CopySelectedButtonPushed(app, event)
            idx = app.getActiveSelection();

            if isempty(idx)
                return
            end

            saveState(app)

            newIdx = [];

            for i = 1:length(idx)

                e2 = app.Elements{idx(i)};

                if isfield(e2,'x')
                    e2.x = e2.x + 50;
                end

                if isfield(e2,'y')
                    e2.y = e2.y + 50;
                end

                if isfield(e2,'type')
                    e2.name = makeDefaultName(app,e2.type);
                end

                app.Elements{end+1} = e2;
                newIdx(end+1) = length(app.Elements);

            end

            app.SelectedElements = newIdx;
            app.SelectedElement = [];

            drawScene(app)
        end

        % Value changed function: MeasureSwitch
        function MeasureSwitchValueChanged(app, event)
            ensureMeasureModeControl(app)

            if app.isSwitchOn(app.MeasureSwitch)

                app.MeasureMode = true;
                app.ShowMeasurements = true;
                app.MeasureStart = [];

                app.MeasureLamp.Color = [0 1 0];

            else

                app.MeasureMode = false;
                app.ShowMeasurements = false;
                app.MeasureStart = [];

                app.MeasureLamp.Color = [0.7 0.7 0.7];

            end

            drawScene(app)
        end

        % Button pushed function: DisableSelectedButton
        function DisableSelectedButtonPushed(app, event)
            idx = app.getActiveSelection();

            if isempty(idx)
                return
            end

            saveState(app)

            for i = 1:length(idx)
                k = idx(i);
                e = app.Elements{k};
                e.disabled = true;
                app.Elements{k} = e;
            end

            drawScene(app)
        end

        % Button pushed function: EnableSelectedButton
        function EnableSelectedButtonPushed(app, event)
            idx = app.getActiveSelection();

            if isempty(idx)
                return
            end

            saveState(app)

            for i = 1:length(idx)
                k = idx(i);
                e = app.Elements{k};
                e.disabled = false;
                app.Elements{k} = e;
            end

            drawScene(app)
        end

        % Value changed function: BoxSelectSwitch
        function BoxSelectSwitchValueChanged(app, event)
            if app.isSwitchOn(app.BoxSelectSwitch)

                app.IsBoxSelecting = true;

                app.BoxSelectLamp.Color = [0 1 0];

                app.UIFigure.WindowButtonDownFcn = @(src,event) app.startBoxSelect();

            else

                app.IsBoxSelecting = false;

                app.BoxSelectLamp.Color = [0.7 0.7 0.7];

                app.SelectedElements = [];
                app.SelectedElement = [];

                app.UIFigure.WindowButtonDownFcn = '';
                app.UIFigure.WindowButtonMotionFcn = '';
                app.UIFigure.WindowButtonUpFcn = '';

                if ~isempty(app.SelectionBoxHandle) && isvalid(app.SelectionBoxHandle)
                    delete(app.SelectionBoxHandle)
                end

                app.SelectionBoxHandle = [];

            end

            drawScene(app)
        end

        % Value changed function: XMinField
        function XMinFieldValueChanged(app, event)
            app.updateAxisLimits();
        end

        % Value changed function: XMaxField
        function XMaxFieldValueChanged(app, event)
            app.updateAxisLimits();
        end

        % Value changed function: YMinField
        function YMinFieldValueChanged(app, event)
            app.updateAxisLimits();
        end

        % Value changed function: YMaxField
        function YMaxFieldValueChanged(app, event)
            app.updateAxisLimits();
        end

        % Button pushed function: AddPointRayButton
        function AddPointRayButtonPushed(app, event)
            data = app.PointRayTable.Data;

            if isempty(data)
                data = {0,'g'};
            else
                data(end+1,:) = {0,'g'};
            end

            app.PointRayTable.Data = data;

            app.PointRayTableCellEdit([]);
        end

        % Button pushed function: DeletePointRayButton
        function DeletePointRayButtonPushed(app, event)
            data = app.PointRayTable.Data;

            if isempty(data)
                return
            end

            idx = app.PointRayTable.Selection;

            if isempty(idx)
                data(end,:) = [];
            else
                row = idx(1);
                data(row,:) = [];
            end

            app.PointRayTable.Data = data;

            app.PointRayTableCellEdit([]);
        end

        % Value changed function: SearchField
        function SearchFieldValueChanged(app, event)
            key = strtrim(app.SearchField.Value);

            app.SelectedElements = [];
            app.SelectedElement = [];

            if isempty(key)
                drawScene(app)
                return
            end

            for k = 1:length(app.Elements)

                e = app.Elements{k};

                if isfield(e,'name')
                    name = e.name;
                else
                    name = e.type;
                end

                if contains(name,key,'IgnoreCase',true)
                    app.SelectedElements(end+1) = k;
                end

            end

            drawScene(app)
        end

        % Button pushed function: AddSurfaceButton
        function AddSurfaceButtonPushed(app, event)
            saveState(app)

            e.type = 'Surface';
            e.name = makeDefaultName(app,e.type);

            cx = mean(app.CurrentXLim);
            cy = mean(app.CurrentYLim);
            offset = 50 * mod(length(app.Elements),5);

            e.x = cx + offset;
            e.y = cy + offset;
            e.theta = 90;
            e.D = 80;

            app.Elements{end+1} = e;
            app.SelectedElement = length(app.Elements);

            drawScene(app)
        end

        % Value changed function: ElementColorDropDown
        function ElementColorDropDownValueChanged(app, event)
            idx = app.getActiveSelection();

            if isempty(idx)
                return
            end

            saveState(app)

            colorName = app.ElementColorDropDown.Value;

            for i = 1:length(idx)

                k = idx(i);
                e = app.Elements{k};

                if any(strcmp(e.type,{'Lens','Mirror','BS','Pinhole','Surface','Camera','Objective'}))

                    e.color = app.getElementColorCode(colorName);

                    app.Elements{k} = e;

                end

            end

            drawScene(app)
        end

        % Value changed function: ObjectiveAngleRangeField
        function ObjectiveAngleRangeFieldValueChanged(app, event)
            if isempty(app.SelectedElement)
                return
            end

            k = app.SelectedElement;
            e = app.Elements{k};

            e.NA = str2double(app.ObjectiveNAField.Value);

            if isnan(e.NA)
                return
            end

            if e.NA > 1
                e.NA = 1;
                app.ObjectiveNAField.Value = '1';
            end

            thetaMax = asind(e.NA);
            e.angleRange = thetaMax;

            app.ObjectiveAngleRangeField.Value = num2str(thetaMax,'%.2f');

            app.Elements{k} = e;

            drawScene(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,...
                ['Do you want to save before closing?' ...
                ' Unsaved changes will be lost.'],...
                'Close App',...
                'Options',{'Save','Close Without Saving','Cancel'},...
                'DefaultOption',1,...
                'CancelOption',3);

            switch selection

                case 'Save'

                    success = app.saveLayout();

                    if success
                        delete(app)
                    end

                case 'Close Without Saving'

                    delete(app)

                case 'Cancel'

                    return

            end

        end

        % Value changed function: PointSourceRangeField
        function PointSourceRangeFieldValueChanged(app, event)
            app.updateSelectedPointSourceAngles();
        end

        % Value changed function: PointSourceRayCountField
        function PointSourceRayCountFieldValueChanged(app, event)
            app.updateSelectedPointSourceAngles();
        end

        % Value changed function: RayCountField
        function RayCountFieldValueChanged(app, event)
    if isempty(app.SelectedElement)
        return
    end

    e = app.Elements{app.SelectedElement};

    if ~strcmp(e.type,'Ray')
        return
    end

    saveState(app)

    e.rayCount = max(1,round(app.RayCountField.Value));

    app.Elements{app.SelectedElement} = e;

    drawScene(app)
        end

        % Value changed function: ViewLockSwitch
        function ViewLockSwitchValueChanged(app, event)
    app.ViewLocked = app.isSwitchOn(app.ViewLockSwitch);
    if app.isSwitchOn(app.ViewLockSwitch)

    app.ViewLocked = true;

    app.ViewLockLamp.Color = [1 0 0];

else

    app.ViewLocked = false;

    app.ViewLockLamp.Color = [0.7 0.7 0.7];

end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)

    msg = sprintf([ ...
        'COSMIC OptiLayout Help\n\n' ...
        'Basic Controls:\n' ...
        '• Left-click an element to select it.\n' ...
        '• Drag an element to move it.\n' ...
        '• Right-click on the canvas to show coordinates.\n' ...
        '• Double-click empty space to clear selection.\n' ...
        '• Mouse wheel: zoom in/out.\n' ...
        '• Drag empty canvas: pan view, unless Lock View is on.\n\n' ...
        'Box Select:\n' ...
        '• Turn Box Select on to select multiple elements.\n' ...
        '• When Box Select is on, right-click and drag on empty space to draw a selection box.\n\n' ...
        'Elements:\n' ...
        '• Ray: parallel beam source. Angle, width, ray count, and color are set in the light-source panel.\n' ...
        '• Point Source: emits multiple rays with user-defined angles and colors. Its properties are set in the point-source controls.\n' ...
        '• Lens: thin lens element. Focal length and diameter are set in the lens/property fields.\n' ...
        '• Objective: its X/Y coordinate refers to the physical objective edge closest to the front focal plane. Objective parameters must be set in the objective controls, not the general lens focal-length field.\n' ...
        '• Pinhole: circle shows the aperture size; dashed line shows the blocking/action plane.\n' ...
        '• Mirror/BS/Surface/Camera: basic optical elements for layout design.\n\n' ...
        'Measure:\n' ...
        '• Measure mode reports the distance between the reference positions of two selected elements.\n' ...
        '• For most elements, the reference position is the element center.\n' ...
        '• For Objective, the reference position is the objective edge coordinate shown in X/Y, not the back focal plane.\n\n' ...
        'Shortcuts:\n' ...
        '• Delete: delete selected element.\n' ...
        '• Ctrl/Cmd + C: copy selected element.\n' ...
        '• Ctrl/Cmd + V: paste element at current view center.\n' ...
        '• Ctrl/Cmd + Z: undo.\n' ...
        '• Ctrl/Cmd + Y: redo.\n' ...
        '• Arrow keys: move selected element.\n\n' ...
        'View:\n' ...
        '• Reset View restores the default axis range.\n' ...
        '• Lock View prevents accidental canvas panning.\n\n' ...
        'Save/Load:\n' ...
        '• Save Layout stores the optical layout.\n' ...
        '• Load Layout restores a saved layout.' ...
    ]);

    uialert(app.UIFigure,msg,'COSMIC OptiLayout Help');

        end

        % Button pushed function: ExportFigureButton
        function ExportFigureButtonPushed(app, event)
            exportCurrentFigure(app)
        end

        % Value changed function: MeasureModeDropDown
        function MeasureModeDropDownValueChanged(app, event)
            drawScene(app)
        end

        % Button pushed function: XAlignButton
        function XAlignButtonPushed(app, event)
            idx = app.getActiveSelection();

            if isempty(idx)
                return
            end

            saveState(app)

            xs = [];
            for i = 1:length(idx)
                e = app.Elements{idx(i)};
                if isfield(e,'x')
                    xs(end+1) = e.x;
                end
            end

            if isempty(xs)
                return
            end

            targetX = mean(xs);

            for i = 1:length(idx)
                k = idx(i);
                e = app.Elements{k};
                if isfield(e,'x')
                    e.x = targetX;
                    app.Elements{k} = e;
                end
            end

            drawScene(app)
        end

        % Button pushed function: YAlignButton
        function YAlignButtonPushed(app, event)
            idx = app.getActiveSelection();

            if isempty(idx)
                return
            end

            saveState(app)

            ys = [];
            for i = 1:length(idx)
                e = app.Elements{idx(i)};
                if isfield(e,'y')
                    ys(end+1) = e.y;
                end
            end

            if isempty(ys)
                return
            end

            targetY = mean(ys);

            for i = 1:length(idx)
                k = idx(i);
                e = app.Elements{k};
                if isfield(e,'y')
                    e.y = targetY;
                    app.Elements{k} = e;
                end
            end

            drawScene(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'parula')
            app.TypeLabel = uilabel(app.UIFigure);
            app.TypeLabel.Text = 'Selected: None';
            app.DeleteSelectedButton = uibutton(app.UIFigure, 'push');
            app.DeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButtonPushed, true);
            app.DeleteSelectedButton.Text = 'Delete Selected';
            app.SaveLayoutButton = uibutton(app.UIFigure, 'push');
            app.SaveLayoutButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLayoutButtonPushed, true);
            app.SaveLayoutButton.Text = 'Save Layout';
            app.LoadLayoutButton = uibutton(app.UIFigure, 'push');
            app.LoadLayoutButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLayoutButtonPushed, true);
            app.LoadLayoutButton.Text = 'Load Layout';
            app.LockSelectedButton = uibutton(app.UIFigure, 'push');
            app.LockSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @LockSelectedButtonPushed, true);
            app.LockSelectedButton.Text = 'Lock Selected';
            app.UnlockSelectedButton = uibutton(app.UIFigure, 'push');
            app.UnlockSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @UnlockSelectedButtonPushed, true);
            app.UnlockSelectedButton.Text = 'Unlock Selected';
            app.ResetViewButton = uibutton(app.UIFigure, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Text = 'VIEW RESET';
            app.ZoomSwitchLabel = uilabel(app.UIFigure);
            app.ZoomSwitchLabel.Text = 'Zoom';
            app.ZoomSwitch = uiswitch(app.UIFigure, 'slider');
            app.ZoomSwitch.Items = {' ', '  '};
            app.ZoomSwitch.ItemsData = {'Off', 'On'};
            app.ZoomSwitch.ValueChangedFcn = createCallbackFcn(app, @ZoomSwitchValueChanged, true);
            app.ZoomLamp = uilamp(app.UIFigure);
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'UNDO';
            app.RedoButton = uibutton(app.UIFigure, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = 'REDO';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name (Press Enter)';
            app.NameField = uieditfield(app.UIFigure, 'text');
            app.NameField.ValueChangedFcn = createCallbackFcn(app, @NameFieldValueChanged, true);
            app.CopySelectedButton = uibutton(app.UIFigure, 'push');
            app.CopySelectedButton.ButtonPushedFcn = createCallbackFcn(app, @CopySelectedButtonPushed, true);
            app.CopySelectedButton.Text = 'Copy Selected';
            app.MeasureSwitchLabel = uilabel(app.UIFigure);
            app.MeasureSwitchLabel.Text = 'Measure';
            app.MeasureSwitch = uiswitch(app.UIFigure, 'slider');
            app.MeasureSwitch.Items = {' ', '  '};
            app.MeasureSwitch.ItemsData = {'Off', 'On'};
            app.MeasureSwitch.ValueChangedFcn = createCallbackFcn(app, @MeasureSwitchValueChanged, true);
            app.MeasureLamp = uilamp(app.UIFigure);
            app.DisableSelectedButton = uibutton(app.UIFigure, 'push');
            app.DisableSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DisableSelectedButtonPushed, true);
            app.DisableSelectedButton.Text = 'Disable Selected';
            app.EnableSelectedButton = uibutton(app.UIFigure, 'push');
            app.EnableSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @EnableSelectedButtonPushed, true);
            app.EnableSelectedButton.Text = 'Enable Selected';
            app.BoxSelectSwitchLabel = uilabel(app.UIFigure);
            app.BoxSelectSwitchLabel.Tooltip = 'Right-click and drag to draw selection box';
            app.BoxSelectSwitchLabel.Text = 'Box Select';
            app.BoxSelectSwitch = uiswitch(app.UIFigure, 'slider');
            app.BoxSelectSwitch.Items = {' ', '  '};
            app.BoxSelectSwitch.ItemsData = {'Off', 'On'};
            app.BoxSelectSwitch.ValueChangedFcn = createCallbackFcn(app, @BoxSelectSwitchValueChanged, true);
            app.BoxSelectSwitch.Tooltip = 'Right-click and drag to draw selection box';
            app.BoxSelectLamp = uilamp(app.UIFigure);
            app.BoxSelectLamp.Tooltip = 'Right-click and drag to draw selection box';
            app.XMinEditFieldLabel = uilabel(app.UIFigure);
            app.XMinEditFieldLabel.Text = 'X Min';
            app.XMinField = uieditfield(app.UIFigure, 'numeric');
            app.XMinField.ValueChangedFcn = createCallbackFcn(app, @XMinFieldValueChanged, true);
            app.XMaxEditFieldLabel = uilabel(app.UIFigure);
            app.XMaxEditFieldLabel.Text = 'X Max';
            app.XMaxField = uieditfield(app.UIFigure, 'numeric');
            app.XMaxField.ValueChangedFcn = createCallbackFcn(app, @XMaxFieldValueChanged, true);
            app.XMaxField.Value = 3000;
            app.YMinEditFieldLabel = uilabel(app.UIFigure);
            app.YMinEditFieldLabel.Text = 'Y Min';
            app.YMinField = uieditfield(app.UIFigure, 'numeric');
            app.YMinField.ValueChangedFcn = createCallbackFcn(app, @YMinFieldValueChanged, true);
            app.YMaxField = uieditfield(app.UIFigure, 'numeric');
            app.YMaxField.ValueChangedFcn = createCallbackFcn(app, @YMaxFieldValueChanged, true);
            app.YMaxField.Value = 1200;
            app.YMaxEditFieldLabel = uilabel(app.UIFigure);
            app.YMaxEditFieldLabel.Text = 'Y Max';
            app.SearchPressEnterLabel = uilabel(app.UIFigure);
            app.SearchPressEnterLabel.Text = 'Search (Press Enter)';
            app.SearchField = uieditfield(app.UIFigure, 'text');
            app.SearchField.ValueChangedFcn = createCallbackFcn(app, @SearchFieldValueChanged, true);
            app.CursorPositionLabel = uilabel(app.UIFigure);
            app.CursorPositionLabel.Text = 'X: ------  , Y:------';
            app.LIGHTSOURCEPanel = uipanel(app.UIFigure);
            app.LIGHTSOURCEPanel.Title = '>>>>>>>>>  LIGHT SOURCE  <<<<<<<<<';
            app.AddRayButton = uibutton(app.LIGHTSOURCEPanel, 'push');
            app.AddRayButton.ButtonPushedFcn = createCallbackFcn(app, @AddRayButtonPushed, true);
            app.AddRayButton.Text = 'Add Ray';
            app.RayAngledegEditFieldLabel = uilabel(app.LIGHTSOURCEPanel);
            app.RayAngledegEditFieldLabel.Text = 'Ray Angle (deg)';
            app.RayAngleField = uieditfield(app.LIGHTSOURCEPanel, 'text');
            app.RayAngleField.ValueChangedFcn = createCallbackFcn(app, @RayAngleFieldButtonPushed, true);
            app.RayAngleField.Value = '0';
            app.RayWidthLabel = uilabel(app.LIGHTSOURCEPanel);
            app.RayWidthLabel.Text = 'Ray Width';
            app.RayWidthField = uieditfield(app.LIGHTSOURCEPanel, 'text');
            app.RayWidthField.ValueChangedFcn = createCallbackFcn(app, @RayWidthFieldValueChanged, true);
            app.RayWidthField.Value = '10';
            app.RayColorLabel = uilabel(app.LIGHTSOURCEPanel);
            app.RayColorLabel.Text = 'Ray Color';
            app.RayColorDropDown = uidropdown(app.LIGHTSOURCEPanel);
            app.RayColorDropDown.Items = {'green', 'red', 'blue', 'cyan', 'magenta', 'yellow', 'black'};
            app.RayColorDropDown.ValueChangedFcn = createCallbackFcn(app, @RayColorDropDownValueChanged, true);
            app.RayColorDropDown.Value = 'green';
            app.AddPointSourceButton = uibutton(app.LIGHTSOURCEPanel, 'push');
            app.AddPointSourceButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointSourceButtonPushed2, true);
            app.AddPointSourceButton.Text = 'Add Point Source';
            app.AddPointRayButton = uibutton(app.LIGHTSOURCEPanel, 'push');
            app.AddPointRayButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointRayButtonPushed, true);
            app.AddPointRayButton.Text = 'Add Point Ray';
            app.DeletePointRayButton = uibutton(app.LIGHTSOURCEPanel, 'push');
            app.DeletePointRayButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePointRayButtonPushed, true);
            app.DeletePointRayButton.Text = 'Delete Point Ray';
            app.PointRayTable = uitable(app.LIGHTSOURCEPanel);
            app.PointRayTable.ColumnName = {'Angle'; 'Color'};
            app.PointRayTable.RowName = {};
            app.PointRayTable.ColumnEditable = [true true];
            app.PointRayTable.CellEditCallback = createCallbackFcn(app, @PointRayTableCellEdit, true);
            app.RangeAngleLabel = uilabel(app.LIGHTSOURCEPanel);
            app.RangeAngleLabel.Text = 'Range Angle';
            app.PointSourceRangeField = uieditfield(app.LIGHTSOURCEPanel, 'numeric');
            app.PointSourceRangeField.ValueChangedFcn = createCallbackFcn(app, @PointSourceRangeFieldValueChanged, true);
            app.PointSourceRangeField.Value = 40;
            app.RayCountEditFieldLabel = uilabel(app.LIGHTSOURCEPanel);
            app.RayCountEditFieldLabel.Text = 'Ray Count';
            app.PointSourceRayCountField = uieditfield(app.LIGHTSOURCEPanel, 'numeric');
            app.PointSourceRayCountField.ValueChangedFcn = createCallbackFcn(app, @PointSourceRayCountFieldValueChanged, true);
            app.PointSourceRayCountField.Value = 5;
            app.RayCountLabel = uilabel(app.LIGHTSOURCEPanel);
            app.RayCountLabel.Text = 'Ray Count';
            app.RayCountField = uieditfield(app.LIGHTSOURCEPanel, 'numeric');
            app.RayCountField.ValueChangedFcn = createCallbackFcn(app, @RayCountFieldValueChanged, true);
            app.RayCountField.Value = 5;
            app.OPTICSPanel = uipanel(app.UIFigure);
            app.OPTICSPanel.Title = '>>>>>>>>>  OPTICS  <<<<<<<<<';
            app.AddObjectiveButton = uibutton(app.OPTICSPanel, 'push');
            app.AddObjectiveButton.ButtonPushedFcn = createCallbackFcn(app, @AddObjectiveButtonPushed, true);
            app.AddObjectiveButton.Text = 'Add Objective';
            app.AperturemmLabel = uilabel(app.OPTICSPanel);
            app.AperturemmLabel.Text = 'Aperture (mm)';
            app.ObjectiveApertureField = uieditfield(app.OPTICSPanel, 'text');
            app.ObjectiveApertureField.ValueChangedFcn = createCallbackFcn(app, @ObjectiveApertureFieldValueChanged, true);
            app.LengthmmLabel = uilabel(app.OPTICSPanel);
            app.LengthmmLabel.Text = 'Length (mm)';
            app.ObjectiveLengthField = uieditfield(app.OPTICSPanel, 'text');
            app.ObjectiveLengthField.ValueChangedFcn = createCallbackFcn(app, @ObjectiveLengthFieldValueChanged, true);
            app.FocaldistancemmLabel = uilabel(app.OPTICSPanel);
            app.FocaldistancemmLabel.Text = 'Focal distance (mm)';
            app.ObjectiveFocalField = uieditfield(app.OPTICSPanel, 'text');
            app.ObjectiveFocalField.ValueChangedFcn = createCallbackFcn(app, @ObjectiveFocalFieldValueChanged, true);
            app.NAEditFieldLabel = uilabel(app.OPTICSPanel);
            app.NAEditFieldLabel.Text = 'NA';
            app.ObjectiveNAField = uieditfield(app.OPTICSPanel, 'text');
            app.ObjectiveNAField.ValueChangedFcn = createCallbackFcn(app, @ObjectiveNAFieldValueChanged, true);
            app.AngleMaxLabel = uilabel(app.OPTICSPanel);
            app.AngleMaxLabel.Text = 'Angle (Max)';
            app.ObjectiveAngleRangeField = uieditfield(app.OPTICSPanel, 'text');
            app.ObjectiveAngleRangeField.ValueChangedFcn = createCallbackFcn(app, @ObjectiveAngleRangeFieldValueChanged, true);
            app.ObjectiveAngleRangeField.Editable = 'off';
            app.AddSampleButton = uibutton(app.OPTICSPanel, 'push');
            app.AddSampleButton.ButtonPushedFcn = createCallbackFcn(app, @AddSampleButtonPushed, true);
            app.AddSampleButton.Text = 'Add Sample';
            app.AddLensButton = uibutton(app.OPTICSPanel, 'push');
            app.AddLensButton.ButtonPushedFcn = createCallbackFcn(app, @AddLensButtonPushed2, true);
            app.AddLensButton.Text = 'Add Lens';
            app.FocallengthLabel = uilabel(app.OPTICSPanel);
            app.FocallengthLabel.Text = 'Focal length (mm)';
            app.FocalLength = uieditfield(app.OPTICSPanel, 'text');
            app.FocalLength.ValueChangedFcn = createCallbackFcn(app, @FocalLengthValueChanged, true);
            app.AddMirrorsButton = uibutton(app.OPTICSPanel, 'push');
            app.AddMirrorsButton.ButtonPushedFcn = createCallbackFcn(app, @AddMirrorsButtonPushed, true);
            app.AddMirrorsButton.Text = 'Add Mirrors';
            app.AddBSButton = uibutton(app.OPTICSPanel, 'push');
            app.AddBSButton.ButtonPushedFcn = createCallbackFcn(app, @AddBSButtonPushed, true);
            app.AddBSButton.Text = 'Add BS';
            app.AddPinholeButton = uibutton(app.OPTICSPanel, 'push');
            app.AddPinholeButton.ButtonPushedFcn = createCallbackFcn(app, @AddPinholeButtonPushed, true);
            app.AddPinholeButton.Text = 'Add Pinhole';
            app.AddSurfaceButton = uibutton(app.OPTICSPanel, 'push');
            app.AddSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @AddSurfaceButtonPushed, true);
            app.AddSurfaceButton.Text = 'Add Surface';
            app.AddCameraButton = uibutton(app.OPTICSPanel, 'push');
            app.AddCameraButton.ButtonPushedFcn = createCallbackFcn(app, @AddCameraButtonPushed, true);
            app.AddCameraButton.Text = 'Add Camera';
            app.PROPERTYPanel = uipanel(app.UIFigure);
            app.PROPERTYPanel.Title = '>>>>>>  PROPERTY  <<<<<<';
            app.RotationAngledegEditFieldLabel = uilabel(app.PROPERTYPanel);
            app.RotationAngledegEditFieldLabel.Text = 'Rotation Angle  (deg)';
            app.RotationAngleField = uieditfield(app.PROPERTYPanel, 'text');
            app.RotationAngleField.ValueChangedFcn = createCallbackFcn(app, @RotationAngleFieldValueChanged, true);
            app.RotationAngleField.Value = '0';
            app.XmmLabel = uilabel(app.PROPERTYPanel);
            app.XmmLabel.Text = 'X (mm)';
            app.XField = uieditfield(app.PROPERTYPanel, 'text');
            app.XField.ValueChangedFcn = createCallbackFcn(app, @XFieldValueChanged, true);
            app.YmmLabel = uilabel(app.PROPERTYPanel);
            app.YmmLabel.Text = 'Y (mm)';
            app.YField = uieditfield(app.PROPERTYPanel, 'text');
            app.YField.ValueChangedFcn = createCallbackFcn(app, @YFieldValueChanged, true);
            app.DiameterLabel = uilabel(app.PROPERTYPanel);
            app.DiameterLabel.Text = 'Diameter (mm)';
            app.Diameter = uieditfield(app.PROPERTYPanel, 'text');
            app.Diameter.ValueChangedFcn = createCallbackFcn(app, @DiameterValueChanged, true);
            app.ElementColorDropDownLabel = uilabel(app.PROPERTYPanel);
            app.ElementColorDropDownLabel.Text = 'Element Color';
            app.ElementColorDropDown = uidropdown(app.PROPERTYPanel);
            app.ElementColorDropDown.Items = {'Gray', 'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Black'};
            app.ElementColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementColorDropDownValueChanged, true);
            app.ElementColorDropDown.Value = 'Gray';
            app.LockViewSwitchLabel = uilabel(app.UIFigure);
            app.LockViewSwitchLabel.Text = 'Lock View';
            app.ViewLockSwitch = uiswitch(app.UIFigure, 'slider');
            app.ViewLockSwitch.Items = {' ', '  '};
            app.ViewLockSwitch.ItemsData = {'Off', 'On'};
            app.ViewLockSwitch.ValueChangedFcn = createCallbackFcn(app, @ViewLockSwitchValueChanged, true);
            app.ViewLockLamp = uilamp(app.UIFigure);
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'HELP';
            app.ExportFigureButton = uibutton(app.UIFigure, 'push');
            app.ExportFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureButtonPushed, true);
            app.ExportFigureButton.Text = 'Export Figure';
            app.MeasureModeDropDown = uidropdown(app.UIFigure);
            app.MeasureModeDropDown.Items = {'Point-to-point', 'Surface-to-surface'};
            app.MeasureModeDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasureModeDropDownValueChanged, true);
            app.MeasureModeDropDown.Value = 'Surface-to-surface';
            app.XAlignButton = uibutton(app.UIFigure, 'push');
            app.XAlignButton.ButtonPushedFcn = createCallbackFcn(app, @XAlignButtonPushed, true);
            app.XAlignButton.Text = 'X Align';
            app.YAlignButton = uibutton(app.UIFigure, 'push');
            app.YAlignButton.ButtonPushedFcn = createCallbackFcn(app, @YAlignButtonPushed, true);
            app.YAlignButton.Text = 'Y Align';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cosmic_optilayout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end